classdef addTorqueDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        addnewbendingmomentPanel  matlab.ui.container.Panel
        ButtonGroup               matlab.ui.container.ButtonGroup
        positiveButton            matlab.ui.control.ToggleButton
        negativeButton            matlab.ui.control.ToggleButton
        valueEditFieldLabel       matlab.ui.control.Label
        valueEF                   matlab.ui.control.NumericEditField
        NmLabel                   matlab.ui.control.Label
        mLabel_2                  matlab.ui.control.Label
        positionxEditFieldLabel   matlab.ui.control.Label
        positionxEF               matlab.ui.control.NumericEditField
        directionLabel            matlab.ui.control.Label
        okButton                  matlab.ui.control.Button
        cancelButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainGUI
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.MainGUI = mainApp;
        end

        % Button pushed function: okButton
        function okButtonPushed(app, event)
            
            value = app.valueEF.Value;
            
            % checking for valid user inputs
            if app.positiveButton.Value
                direction = 1;
            else
                direction = -1;
            end
            
            position = app.positionxEF.Value;
            
            if value<=0
                uialert(app.UIFigure,"Please enter a valid value (greater than zero)","Error");
           
            elseif position<0
                uialert(app.UIFigure,"Please enter a valid position (greater than zero)","Error");
                
            else
                app.MainGUI.addTorque(value,direction,position);
                delete(app);
            end 
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.addnewbendingmomentPanel = uipanel(app.UIFigure);
            app.addnewbendingmomentPanel.Title = 'add new bending moment';
            app.ButtonGroup = uibuttongroup(app.addnewbendingmomentPanel);
            app.positiveButton = uitogglebutton(app.ButtonGroup);
            app.positiveButton.Text = '+';
            app.positiveButton.Value = true;
            app.negativeButton = uitogglebutton(app.ButtonGroup);
            app.negativeButton.Text = '-';
            app.valueEditFieldLabel = uilabel(app.addnewbendingmomentPanel);
            app.valueEditFieldLabel.Text = 'value';
            app.valueEF = uieditfield(app.addnewbendingmomentPanel, 'numeric');
            app.NmLabel = uilabel(app.addnewbendingmomentPanel);
            app.NmLabel.Text = 'Nm';
            app.mLabel_2 = uilabel(app.addnewbendingmomentPanel);
            app.mLabel_2.Text = 'm';
            app.positionxEditFieldLabel = uilabel(app.addnewbendingmomentPanel);
            app.positionxEditFieldLabel.Text = 'position (x)';
            app.positionxEF = uieditfield(app.addnewbendingmomentPanel, 'numeric');
            app.directionLabel = uilabel(app.addnewbendingmomentPanel);
            app.directionLabel.Text = 'direction';
            app.okButton = uibutton(app.UIFigure, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Text = 'OK';
            app.cancelButton = uibutton(app.UIFigure, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Text = 'cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = addTorqueDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end