classdef mainGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        staticscalculationLabel  matlab.ui.control.Label
        panelTables              matlab.ui.container.Panel
        lineloadsLabel           matlab.ui.control.Label
        table_lineload           matlab.ui.control.Table
        bendingmomentsLabel      matlab.ui.control.Label
        table_bendingmoments     matlab.ui.control.Table
        forcesLabel              matlab.ui.control.Label
        table_forces             matlab.ui.control.Table
        panelMain                matlab.ui.container.Panel
        parametersPanel          matlab.ui.container.Panel
        resetButton              matlab.ui.control.Button
        calculateButton          matlab.ui.control.Button
        selectformPanel          matlab.ui.container.Panel
        BGSelectForm             matlab.ui.container.ButtonGroup
        type1Button              matlab.ui.control.ToggleButton
        type2Button              matlab.ui.control.ToggleButton
        type3Button              matlab.ui.control.ToggleButton
        type4Button              matlab.ui.control.ToggleButton
        Panel_2                  matlab.ui.container.Panel
        mLabel                   matlab.ui.control.Label
        EFLength                 matlab.ui.control.NumericEditField
        lenghtlEditFieldLabel    matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        addloadsPanel            matlab.ui.container.Panel
        addlineloadButton        matlab.ui.control.Button
        addbendingmomentButton   matlab.ui.control.Button
        addforceButton           matlab.ui.control.Button
        panelTypes               matlab.ui.container.ButtonGroup
        panelType4               matlab.ui.container.Panel
        Image4                   matlab.ui.control.Image
        panelType3               matlab.ui.container.Panel
        Image3                   matlab.ui.control.Image
        panelType2               matlab.ui.container.Panel
        Image2                   matlab.ui.control.Image
        panelType1               matlab.ui.container.Panel
        Image1                   matlab.ui.control.Image
    end

    
    properties (Access = private)       
        load Load
        beamLength
        type
        calculations Calculations
        
        addForceDlg
        addTorqueDlg
        addLineLoadDlg
        
        resultsGUI
        
        handleWaitBar
    end
    
    
    methods (Access = private)
        
        % function update
        % is called every time a new load is added
        % puts the stored values of "load" into the tables of the gui
        function update(app)
                      
            % filling the forces-table
            if app.load.Forces(1).Value ~= 0
                
                for i=1:length(app.load.Forces)
                    
                    app.load.Forces(i).Name = "F"+num2str(i);
                    
                    dataForces(i,1) = app.load.Forces(i).Name;
                    dataForces(i,2) = app.load.Forces(i).Value;
                    dataForces(i,3) = app.load.Forces(i).Position;
                    dataForces(i,4) = app.load.Forces(i).Angle; 
                end
                
            else                
                dataForces(1,1) = "";
                dataForces(1,2) = "";
                dataForces(1,3) = "";
                dataForces(1,4) = "";
            end
            
            % filling the torques-table    
            if app.load.Torques(1).Value ~= 0
                
                for i=1:length(app.load.Torques)
                    
                    app.load.Torques(i).Name = "M"+num2str(i);
                    
                    dataTorques(i,1) = app.load.Torques(i).Name;
                    dataTorques(i,2) = app.load.Torques(i).Value;
                    dataTorques(i,3) = app.load.Torques(i).Direction;
                    dataTorques(i,4) = app.load.Torques(i).Position;  
                end
                
            else                
                dataTorques(1,1) = "";
                dataTorques(1,2) = "";
                dataTorques(1,3) = "";
                dataTorques(1,4) = "";
            end
            
            % filling the line loads-table
            if ~strcmp(app.load.LineLoads(1).Equation,"0")
                
                for i=1:length(app.load.LineLoads)
                    
                    app.load.LineLoads(i).Name = "q"+num2str(i);
                    
                    dataLineLoads(i,1) = app.load.LineLoads(i).Name;
                    dataLineLoads(i,2) = app.load.LineLoads(i).Equation;
                    dataLineLoads(i,3) = app.load.LineLoads(i).StartPoint;
                    dataLineLoads(i,4) = app.load.LineLoads(i).EndPoint;                  
                end
                
            else                
                dataLineLoads(1,1) = "";
                dataLineLoads(1,2) = "";
                dataLineLoads(1,3) = "";
                dataLineLoads(1,4) = "";
            end
            

            app.table_forces.Data = dataForces;
            app.table_bendingmoments.Data = dataTorques;
            app.table_lineload.Data = dataLineLoads;

        end


        % function check
        % is called when pressing the calculate button
        % checks for valid user inputs
        function check = checkInputs(app, beamLength, forces, torques, lineLoads)
            
            check = true;
            
            if beamLength<=0
                uialert(app.UIFigure,"Please enter a valid length (greater than zero)","Error");
                check = false;
            
            else
                
                for i=1:length(forces)
                    
                    if forces(i).Position < 0 || forces(i).Position > beamLength
                        uialert(app.UIFigure,"The Position of " + forces(i).Name + " must be between 0 and the beam's length","Error");
                        check = false;
                    end
                end
                                    
                for i=1:length(torques)    
                    
                    if torques(i).Position < 0 || torques(i).Position > beamLength
                        uialert(app.UIFigure,"The Position of " + app.load.Torques(i).Name + " must be between 0 and the beam's length","Error");
                        check = false;
                    end
                end
                    
                for i=1:length(lineLoads)
                    
                    if lineLoads(i).EndPoint > beamLength
                        uialert(app.UIFigure,"The end of " + lineLoads(i).Name + " may not be greater than the beam's length","Error");
                        check = false;
                    end
                end
            end
        end

    end


    methods (Access = public)
        
        % funtion addForce
        % is called by the addForceDialog
        % puts the values of the dialog into "load" at the according position (dependent on the x-position of the force)
        function addForce(app, value, position, angle)
            
            forces = app.load.Forces;
            newForce = Force("F",value,position,angle);
 
            if forces(1).Value == 0
                forces(1) = newForce;
                
            elseif newForce.Position >= forces(length(forces)).Position
                forces(length(forces)+1) = newForce;
                
            else
                for i=1:length(forces)
                    
                    if newForce.Position < forces(i).Position
                        
                        tempForces = forces(i:end);
                        forces(i) = newForce;
                        
                        for j=1:length(tempForces)
                            forces(i+j) = tempForces(j);
                        end
                        
                        break
                    end
                end
            end
            
            app.load.Forces = forces;
            app.update();
            
        end


        % funtion addTorque
        % is called by the addTorqueDialog
        % puts the values of the dialog into "load" at the according position (dependent on the x-position of the torque)
        function addTorque(app, value, direction, position)          
            
            torques = app.load.Torques;
            newTorque = Torque("M",value,direction,position);
 
            if torques(1).Value == 0
                torques(1) = newTorque;
                
            elseif newTorque.Position >= torques(length(torques)).Position
                torques(length(torques)+1) = newTorque;
                
            else
                for i=1:length(torques)
                    
                    if newTorque.Position < torques(i).Position
                        
                        tempTorques = torques(i:end);
                        torques(i) = newTorque;
                        
                        for j=1:length(tempTorques)
                            torques(i+j) = torques(j);
                        end
                        
                        break
                    end
                end
            end
            
            app.load.Torques = torques;
            app.update();
            
        end


        % funtion addLineLoad
        % is called by the addLineLoadDialog
        % puts the values of the dialog into "load" at the according position (dependent on the start point of the line load)
        function addLineLoad(app, equation, startPoint, endPoint)
            
            lineLoads = app.load.LineLoads;
            newLineLoad = LineLoad("q",equation,startPoint,endPoint);
 
            if strcmp(lineLoads(1).Equation,"0")
                lineLoads(1) = newLineLoad;
                
            elseif newLineLoad.StartPoint >= lineLoads(length(lineLoads)).StartPoint
                lineLoads(length(lineLoads)+1) = newLineLoad;
                
            else
                for i=1:length(lineLoads)
                    
                    if newLineLoad.StartPoint < lineLoads(i).StartPoint
                        
                        tempLineLoads = lineLoads(i:end);
                        lineLoads(i) = newLineLoad;
                        
                        for j=1:length(tempLineLoads)
                            lineLoads(i+j) = tempLineLoads(j);
                        end
                        
                        break
                    end
                end
            end
            
            app.load.LineLoads = lineLoads;
            app.update();
            
        end
        
        
        % function reset
        % is called when pressing the reset button
        % clears "load" and the tables of the gui
        function reset(app)
            app.load = Load(Force("F0",0,0,0), Torque("M0",0,1,0), LineLoad("q0","0",0,0));
            app.EFLength.Value = 0;
            app.type1Button.Value = true;
            app.update();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Image1.ImageSource = 'gui\data\Type1.jpg';
            app.Image2.ImageSource = 'gui\data\Type2.jpg';
            app.Image3.ImageSource = 'gui\data\Type3.jpg';
            app.Image4.ImageSource = 'gui\data\Type4.jpg';
            
            
            app.load = Load(Force("F0",0,0,0), Torque("M0",0,1,0), LineLoad("q0","0",0,0));
            app.update();
        end

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
           
            app.beamLength = app.EFLength.Value;
            
            % handling the wait bar
            if app.checkInputs(app.beamLength, app.load.Forces, app.load.Torques, app.load.LineLoads)

                app.handleWaitBar = waitbar(0.33, "Please wait, calculation in progress - This may take up to several seconds");
                
                if app.type1Button.Value
                    app.type = 1;
                    app.calculations = staticsCalculation(app.load,app.beamLength,app.type);
                    app.handleWaitBar = waitbar(0.66, app.handleWaitBar, "Please wait, calculation in progress - This may take up to several seconds");
                end
                
                if app.type2Button.Value
                    app.type = 2;
                    app.calculations = staticsCalculation(app.load,app.beamLength,app.type);
                    app.handleWaitBar = waitbar(0.66, app.handleWaitBar, "Please wait, calculation in progress - This may take up to several seconds");
                end
                
                if app.type3Button.Value
                    app.type = 3;
                    app.calculations = staticsCalculation(app.load,app.beamLength,app.type);
                    app.handleWaitBar = waitbar(0.66, app.handleWaitBar, "Please wait, calculation in progress - This may take up to several seconds");
                end
                
                if app.type4Button.Value
                    app.type = 4;
                    app.calculations = staticsCalculation(app.load,app.beamLength,app.type);
                    app.handleWaitBar = waitbar(0.66, app.handleWaitBar, "Please wait, calculation in progress - This may take up to several seconds");
                end
                                
                app.handleWaitBar = waitbar(1, app.handleWaitBar, "Please wait, calculation in progress - This may take up to several seconds");
                delete(app.handleWaitBar);
                
                
                app.calculateButton.Enable = false;
            
                app.resultsGUI = results(app, app.load, app.calculations);

            end
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            answer = questdlg("Do you really want to start a new calculation? - All previously entered data will be lost", "Confirmation", "Yes", "No", "modal");
            
            if strcmp(answer,"Yes")
                app.reset();
            end
        end

        % Button pushed function: addforceButton
        function addforceButtonPushed(app, event)
            app.addForceDlg = addForceDialog(app);
        end

        % Button pushed function: addbendingmomentButton
        function addbendingmomentButtonPushed(app, event)
            app.addTorqueDlg = addTorqueDialog(app);
        end

        % Button pushed function: addlineloadButton
        function addlineloadButtonPushed(app, event)
            app.addLineLoadDlg = addLineLoadDialog(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            answer = questdlg("Do you really want to quit the programm?", "Confirmation", "Yes", "No", "modal");
            
            if strcmp(answer,"Yes")
                delete(app.addForceDlg);
                delete(app.addTorqueDlg);
                delete(app.addLineLoadDlg);
                delete(app.resultsGUI);
                delete(answer);
                delete(app.handleWaitBar);
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.panelMain = uipanel(app.UIFigure);
            app.panelTypes = uibuttongroup(app.panelMain);
            app.panelType1 = uipanel(app.panelTypes);
            app.panelType1.Title = '1';
            app.Image1 = uiimage(app.panelType1);
            app.Image1.ImageSource = 'HelpWindowGraphic.jpg';
            app.panelType2 = uipanel(app.panelTypes);
            app.panelType2.Title = '2';
            app.Image2 = uiimage(app.panelType2);
            app.Image2.ImageSource = 'Type2.jpg';
            app.panelType3 = uipanel(app.panelTypes);
            app.panelType3.Title = '3';
            app.Image3 = uiimage(app.panelType3);
            app.Image3.ImageSource = 'Type3.jpg';
            app.panelType4 = uipanel(app.panelTypes);
            app.panelType4.Title = '4';
            app.Image4 = uiimage(app.panelType4);
            app.Image4.ImageSource = 'Type4.jpg';
            app.parametersPanel = uipanel(app.panelMain);
            app.parametersPanel.Title = 'parameters';
            app.addloadsPanel = uipanel(app.parametersPanel);
            app.addloadsPanel.Title = 'add loads';
            app.addforceButton = uibutton(app.addloadsPanel, 'push');
            app.addforceButton.ButtonPushedFcn = createCallbackFcn(app, @addforceButtonPushed, true);
            app.addforceButton.Text = 'add force';
            app.addbendingmomentButton = uibutton(app.addloadsPanel, 'push');
            app.addbendingmomentButton.ButtonPushedFcn = createCallbackFcn(app, @addbendingmomentButtonPushed, true);
            app.addbendingmomentButton.Text = 'add bending moment';
            app.addlineloadButton = uibutton(app.addloadsPanel, 'push');
            app.addlineloadButton.ButtonPushedFcn = createCallbackFcn(app, @addlineloadButtonPushed, true);
            app.addlineloadButton.Text = 'add line load';
            app.selectformPanel = uipanel(app.parametersPanel);
            app.selectformPanel.Title = 'select form';
            app.Panel_2 = uipanel(app.selectformPanel);
            app.EditField = uieditfield(app.Panel_2, 'numeric');
            app.lenghtlEditFieldLabel = uilabel(app.Panel_2);
            app.lenghtlEditFieldLabel.Text = 'lenght l: ';
            app.EFLength = uieditfield(app.Panel_2, 'numeric');
            app.mLabel = uilabel(app.Panel_2);
            app.mLabel.Text = 'm';
            app.BGSelectForm = uibuttongroup(app.selectformPanel);
            app.type4Button = uitogglebutton(app.BGSelectForm);
            app.type4Button.Text = '4';
            app.type3Button = uitogglebutton(app.BGSelectForm);
            app.type3Button.Text = '3';
            app.type2Button = uitogglebutton(app.BGSelectForm);
            app.type2Button.Text = '2';
            app.type1Button = uitogglebutton(app.BGSelectForm);
            app.type1Button.Text = '1';
            app.type1Button.Value = true;
            app.calculateButton = uibutton(app.parametersPanel, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.resetButton = uibutton(app.parametersPanel, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.panelTables = uipanel(app.UIFigure);
            app.table_forces = uitable(app.panelTables);
            app.table_forces.ColumnName = {'name'; 'value [N]'; 'position [m]'; 'angle [°]'};
            app.table_forces.RowName = {};
            app.forcesLabel = uilabel(app.panelTables);
            app.forcesLabel.Text = 'forces';
            app.table_bendingmoments = uitable(app.panelTables);
            app.table_bendingmoments.ColumnName = {'name'; 'value [Nm]'; 'direction'; 'position [m]'};
            app.table_bendingmoments.RowName = {};
            app.bendingmomentsLabel = uilabel(app.panelTables);
            app.bendingmomentsLabel.Text = 'bending moments';
            app.table_lineload = uitable(app.panelTables);
            app.table_lineload.ColumnName = {'name'; 'equation [N/m]'; 'start [m]'; 'end [m]'};
            app.table_lineload.RowName = {};
            app.lineloadsLabel = uilabel(app.panelTables);
            app.lineloadsLabel.Text = 'lineloads';
            app.staticscalculationLabel = uilabel(app.UIFigure);
            app.staticscalculationLabel.Text = 'statics calculation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end