classdef results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        graphsPanel              matlab.ui.container.Panel
        HelpButton               matlab.ui.control.Button
        graph_n                  matlab.ui.control.UIAxes
        graph_q                  matlab.ui.control.UIAxes
        graph_my                 matlab.ui.control.UIAxes
        resultsLabel             matlab.ui.control.Label
        bearingsPanel            matlab.ui.container.Panel
        AxEditFieldLabel         matlab.ui.control.Label
        bearings_ax              matlab.ui.control.NumericEditField
        AzEditFieldLabel         matlab.ui.control.Label
        bearings_az              matlab.ui.control.NumericEditField
        MAyEditFieldLabel        matlab.ui.control.Label
        bearings_may             matlab.ui.control.NumericEditField
        NLabel                   matlab.ui.control.Label
        NLabel_2                 matlab.ui.control.Label
        NmLabel                  matlab.ui.control.Label
        BxEditFieldLabel         matlab.ui.control.Label
        bearings_bx              matlab.ui.control.NumericEditField
        BzEditFieldLabel         matlab.ui.control.Label
        bearings_bz              matlab.ui.control.NumericEditField
        MByEditFieldLabel        matlab.ui.control.Label
        bearings_mby             matlab.ui.control.NumericEditField
        NLabel_3                 matlab.ui.control.Label
        NLabel_4                 matlab.ui.control.Label
        NmLabel_2                matlab.ui.control.Label
        maxloadsPanel            matlab.ui.container.Panel
        NEditFieldLabel          matlab.ui.control.Label
        maxloads_n               matlab.ui.control.NumericEditField
        QEditFieldLabel          matlab.ui.control.Label
        maxloads_q               matlab.ui.control.NumericEditField
        MbEditFieldLabel         matlab.ui.control.Label
        maxloads_mb              matlab.ui.control.NumericEditField
        NLabel_5                 matlab.ui.control.Label
        NLabel_6                 matlab.ui.control.Label
        NmLabel_3                matlab.ui.control.Label
        mLabel                   matlab.ui.control.Label
        mLabel_2                 matlab.ui.control.Label
        mLabel_3                 matlab.ui.control.Label
        atxEditFieldLabel        matlab.ui.control.Label
        maxloads_n_pos           matlab.ui.control.EditField
        atxEditField_2Label      matlab.ui.control.Label
        maxloads_q_pos           matlab.ui.control.EditField
        atxEditField_3Label      matlab.ui.control.Label
        maxloads_mb_pos          matlab.ui.control.EditField
        exportdataascsvButton    matlab.ui.control.Button
        newcalculationButton     matlab.ui.control.Button
        exportgraphsaspdfButton  matlab.ui.control.Button
        loadindicatorsPanel      matlab.ui.container.Panel
        ImageBlueLine            matlab.ui.control.Image
        ImageGreenLine           matlab.ui.control.Image
        ImageMagentaLine         matlab.ui.control.Image
        forcesLabel              matlab.ui.control.Label
        bendingmomentsLabel      matlab.ui.control.Label
        lineloadsLabel           matlab.ui.control.Label
        ContextMenu              matlab.ui.container.ContextMenu
        Menu                     matlab.ui.container.Menu
        Menu2                    matlab.ui.container.Menu
    end

    
    properties (Access = private)
        mainGUI
        graphs
        x
    end
    
    methods (Access = private)
        
        % function plotIndicators
        % plots the indicators to the axes
        function plotIndicators(~, handleAxes, indicatorTypes, load)
            
            if load.Forces(1).Value ~= 0
                for i=1:length(indicatorTypes.Forces)
                    plot(handleAxes, indicatorTypes.Forces(i).SingleIndicator(1,:), indicatorTypes.Forces(i).SingleIndicator(2,:), "Color","blue");
                    text(handleAxes, indicatorTypes.Forces(i).IndicatorLabelPos(1), indicatorTypes.Forces(i).IndicatorLabelPos(2), indicatorTypes.Forces(i).IndicatorLabel, "Color","blue");
                end
            end
            
            if load.Torques(1).Value ~= 0
                for i=1:length(indicatorTypes.Torques)
                    plot(handleAxes, indicatorTypes.Torques(i).SingleIndicator(1,:), indicatorTypes.Torques(i).SingleIndicator(2,:), "Color","green");
                    text(handleAxes, indicatorTypes.Torques(i).IndicatorLabelPos(1), indicatorTypes.Torques(i).IndicatorLabelPos(2), indicatorTypes.Torques(i).IndicatorLabel, "Color","green");
                end
            end
            
            if ~strcmp(load.LineLoads(1).Equation,"0")
                for i=1:length(indicatorTypes.LineLoads)
                    plot(handleAxes, indicatorTypes.LineLoads(i).SingleIndicator(1,:), indicatorTypes.LineLoads(i).SingleIndicator(2,:), "Color","magenta");
                    text(handleAxes, indicatorTypes.LineLoads(i).IndicatorLabelPos(1), indicatorTypes.LineLoads(i).IndicatorLabelPos(2), indicatorTypes.LineLoads(i).IndicatorLabel, "Color","magenta");
                end
            end
        end
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, load, calculations)
            app.ImageBlueLine.ImageSource = 'gui\data\blueLine.png';
            app.ImageGreenLine.ImageSource = 'gui\data\greenLine.png';
            app.ImageMagentaLine.ImageSource = 'gui\data\magentaLine.png';
            
            % extracting data
            app.mainGUI = mainApp;
            
            app.x = calculations.X;
            reaction = calculations.Reaction;
            indicators = calculations.Indicators;
            
            
            % setting bearing reaction values
            app.bearings_ax.set("Value",reaction.BearingLoads.A.Fx);
            app.bearings_az.set("Value",reaction.BearingLoads.A.Fz);
            app.bearings_may.set("Value",reaction.BearingLoads.A.My);
            
            app.bearings_bx.set("Value",reaction.BearingLoads.B.Fx);
            app.bearings_bz.set("Value",reaction.BearingLoads.B.Fz);
            app.bearings_mby.set("Value",reaction.BearingLoads.B.My);
            
            
            %setting max load values
            app.maxloads_n.set("Value",reaction.MaxLoads.MaxN.Value);
            app.maxloads_n_pos.set("Value",reaction.MaxLoads.MaxN.Position);
            app.maxloads_q.set("Value",reaction.MaxLoads.MaxQ.Value);
            app.maxloads_q_pos.set("Value",reaction.MaxLoads.MaxQ.Position);
            app.maxloads_mb.set("Value",reaction.MaxLoads.MaxMb.Value);
            app.maxloads_mb_pos.set("Value",reaction.MaxLoads.MaxMb.Position);


            % PLOTTING
            hold(app.graph_n, "on");
            hold(app.graph_q, "on");
            hold(app.graph_my, "on");
            
            % plotting the indicators
            app.plotIndicators(app.graph_n, indicators.IndicatorsN, load);
            app.plotIndicators(app.graph_q, indicators.IndicatorsQ, load);
            app.plotIndicators(app.graph_my, indicators.IndicatorsMb, load);
            
            % plotting the graphs            
            plot(app.graph_n, app.x, reaction.Graphs.N, "Color","red");
            plot(app.graph_q, app.x, reaction.Graphs.Q, "Color","red");
            plot(app.graph_my, app.x, reaction.Graphs.Mb, "Color","red");
            
           
            % setting properties for export
            app.graphs = reaction.Graphs;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainGUI.calculateButton.Enable = true;
            delete(app);
        end

        % Button pushed function: newcalculationButton
        function newcalculationButtonPushed(app, event)
            
            answer = questdlg("Do you really want to start a new calculation? - All previously entered data will be lost", "Confirmation", "Yes", "No", "modal");
            
            if strcmp(answer,"Yes")
                app.mainGUI.reset();
                app.mainGUI.calculateButton.Enable = true;
                delete(app);
            end
        end

        % Button pushed function: exportdataascsvButton
        function exportdataascsvButtonPushed(app, event)
            
            [fileName, filePath] = uiputfile(".csv");
            
            path = convertStringsToChars(convertCharsToStrings(filePath) + convertCharsToStrings(fileName));
            
            if path~=0
                
                fileID = fopen(path,"w");
              
                            
                % output of bearing loads
                bearingLoadsNames = ["Ax [N]","Az [N]","MAy [Nm]","Bx [N]","Bz [N]","MBy [Nm]"];
                bearingLoadsValues = [app.bearings_ax.Value, app.bearings_az.Value, app.bearings_may.Value, app.bearings_bx.Value, app.bearings_bz.Value, app.bearings_mby.Value];
                
                fprintf(fileID,"bearing loads:\n");
                
                for i=1:6
                  fprintf(fileID, "%s;%d\n", bearingLoadsNames(i), bearingLoadsValues(i));
                end
                
                                
                % output of max loads
                maxLoadsNames = ["Nmax [N]","Qmax [N]","Mbmax [Nm]"];
                maxLoadsValues = [app.maxloads_n.Value, app.maxloads_q.Value, app.maxloads_mb.Value];
                maxLoadsPosNames = ["at x [m]", "at x [m]", "at x [m]",];
                maxLoadsPosValues = [app.maxloads_n_pos.Value, app.maxloads_q_pos.Value, app.maxloads_mb_pos.Value];
                
                fprintf(fileID,"\n\nmax. loads:\n");
                
                for i=1:3
                  fprintf(fileID, "%s;%d;%s;%d\n",maxLoadsNames(i),maxLoadsValues(i),maxLoadsPosNames(i),maxLoadsPosValues(i));
                end
                
                
                % output of graphs
                fprintf(fileID,"\n\ngraph vectors:\n");
                
                fprintf(fileID,"x [m];");
                
                for i=1:length(app.x)
                  fprintf(fileID, "%d;",app.x(i));
                end
                
                fprintf(fileID,"\n\nN(x) [N];");
                
                for i=1:length(app.x)
                  fprintf(fileID, "%d;",app.graphs.N(i));
                end
                
                fprintf(fileID,"\n\nQ(x) [N];");
                
                for i=1:length(app.x)
                  fprintf(fileID, "%d;",app.graphs.Q(i));
                end
                
                fprintf(fileID,"\n\nMb(x) [Nm];");
                
                for i=1:length(app.x)
                  fprintf(fileID, "%d;",app.graphs.Mb(i));
                end
                
                fclose(fileID);
            else
                warning("the process of saving your data as .csv was cancelled - please try again");
            end
        end

        % Button pushed function: exportgraphsaspdfButton
        function exportgraphsaspdfButtonPushed(app, event)
            [filename, filepath] = uiputfile(".pdf");

            path = convertStringsToChars(convertCharsToStrings(filepath) + convertCharsToStrings(filename));

            if path~=0
                exportgraphics(app.graphsPanel,path);
            else
                warning("the process of saving your graphs as .pdf was cancelled - please try again");
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            helpGraphs();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.graphsPanel = uipanel(app.UIFigure);
            app.graphsPanel.Title = 'graphs';
            app.HelpButton = uibutton(app.graphsPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.graph_n = uiaxes(app.graphsPanel);
            title(app.graph_n, 'normal force distribution')
            xlabel(app.graph_n, 'x [m]')
            ylabel(app.graph_n, 'N [N]')
            zlabel(app.graph_n, 'Z')
            app.graph_n.YDir = 'reverse';
            app.graph_q = uiaxes(app.graphsPanel);
            title(app.graph_q, 'shear force distribution')
            xlabel(app.graph_q, 'x [m]')
            ylabel(app.graph_q, 'Q [N]')
            zlabel(app.graph_q, 'Z')
            app.graph_q.YDir = 'reverse';
            app.graph_my = uiaxes(app.graphsPanel);
            title(app.graph_my, 'bending moment distribution')
            xlabel(app.graph_my, 'x [m]')
            ylabel(app.graph_my, 'Mb [Nm]')
            zlabel(app.graph_my, 'Z')
            app.graph_my.YDir = 'reverse';
            app.resultsLabel = uilabel(app.UIFigure);
            app.resultsLabel.Text = 'results';
            app.bearingsPanel = uipanel(app.UIFigure);
            app.bearingsPanel.Title = 'bearings';
            app.AxEditFieldLabel = uilabel(app.bearingsPanel);
            app.AxEditFieldLabel.Text = 'Ax=';
            app.bearings_ax = uieditfield(app.bearingsPanel, 'numeric');
            app.bearings_ax.Editable = 'off';
            app.AzEditFieldLabel = uilabel(app.bearingsPanel);
            app.AzEditFieldLabel.Text = 'Az=';
            app.bearings_az = uieditfield(app.bearingsPanel, 'numeric');
            app.bearings_az.Editable = 'off';
            app.MAyEditFieldLabel = uilabel(app.bearingsPanel);
            app.MAyEditFieldLabel.Text = 'MAy=';
            app.bearings_may = uieditfield(app.bearingsPanel, 'numeric');
            app.bearings_may.Editable = 'off';
            app.NLabel = uilabel(app.bearingsPanel);
            app.NLabel.Text = 'N';
            app.NLabel_2 = uilabel(app.bearingsPanel);
            app.NLabel_2.Text = 'N';
            app.NmLabel = uilabel(app.bearingsPanel);
            app.NmLabel.Text = 'Nm';
            app.BxEditFieldLabel = uilabel(app.bearingsPanel);
            app.BxEditFieldLabel.Text = 'Bx=';
            app.bearings_bx = uieditfield(app.bearingsPanel, 'numeric');
            app.bearings_bx.Editable = 'off';
            app.BzEditFieldLabel = uilabel(app.bearingsPanel);
            app.BzEditFieldLabel.Text = 'Bz=';
            app.bearings_bz = uieditfield(app.bearingsPanel, 'numeric');
            app.bearings_bz.Editable = 'off';
            app.MByEditFieldLabel = uilabel(app.bearingsPanel);
            app.MByEditFieldLabel.Text = 'MBy=';
            app.bearings_mby = uieditfield(app.bearingsPanel, 'numeric');
            app.bearings_mby.Editable = 'off';
            app.NLabel_3 = uilabel(app.bearingsPanel);
            app.NLabel_3.Text = 'N';
            app.NLabel_4 = uilabel(app.bearingsPanel);
            app.NLabel_4.Text = 'N';
            app.NmLabel_2 = uilabel(app.bearingsPanel);
            app.NmLabel_2.Text = 'Nm';
            app.maxloadsPanel = uipanel(app.UIFigure);
            app.maxloadsPanel.Title = 'max. loads';
            app.NEditFieldLabel = uilabel(app.maxloadsPanel);
            app.NEditFieldLabel.Text = 'N=';
            app.maxloads_n = uieditfield(app.maxloadsPanel, 'numeric');
            app.maxloads_n.Editable = 'off';
            app.QEditFieldLabel = uilabel(app.maxloadsPanel);
            app.QEditFieldLabel.Text = 'Q=';
            app.maxloads_q = uieditfield(app.maxloadsPanel, 'numeric');
            app.maxloads_q.Editable = 'off';
            app.MbEditFieldLabel = uilabel(app.maxloadsPanel);
            app.MbEditFieldLabel.Text = 'Mb=';
            app.maxloads_mb = uieditfield(app.maxloadsPanel, 'numeric');
            app.maxloads_mb.Editable = 'off';
            app.NLabel_5 = uilabel(app.maxloadsPanel);
            app.NLabel_5.Text = 'N';
            app.NLabel_6 = uilabel(app.maxloadsPanel);
            app.NLabel_6.Text = 'N';
            app.NmLabel_3 = uilabel(app.maxloadsPanel);
            app.NmLabel_3.Text = 'Nm';
            app.mLabel = uilabel(app.maxloadsPanel);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.maxloadsPanel);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.maxloadsPanel);
            app.mLabel_3.Text = 'm';
            app.atxEditFieldLabel = uilabel(app.maxloadsPanel);
            app.atxEditFieldLabel.Text = 'at x=';
            app.maxloads_n_pos = uieditfield(app.maxloadsPanel, 'text');
            app.maxloads_n_pos.Editable = 'off';
            app.atxEditField_2Label = uilabel(app.maxloadsPanel);
            app.atxEditField_2Label.Text = 'at x=';
            app.maxloads_q_pos = uieditfield(app.maxloadsPanel, 'text');
            app.maxloads_q_pos.Editable = 'off';
            app.atxEditField_3Label = uilabel(app.maxloadsPanel);
            app.atxEditField_3Label.Text = 'at x=';
            app.maxloads_mb_pos = uieditfield(app.maxloadsPanel, 'text');
            app.maxloads_mb_pos.Editable = 'off';
            app.exportdataascsvButton = uibutton(app.UIFigure, 'push');
            app.exportdataascsvButton.ButtonPushedFcn = createCallbackFcn(app, @exportdataascsvButtonPushed, true);
            app.exportdataascsvButton.Text = 'export data as .csv';
            app.newcalculationButton = uibutton(app.UIFigure, 'push');
            app.newcalculationButton.ButtonPushedFcn = createCallbackFcn(app, @newcalculationButtonPushed, true);
            app.newcalculationButton.Text = 'new calculation';
            app.exportgraphsaspdfButton = uibutton(app.UIFigure, 'push');
            app.exportgraphsaspdfButton.ButtonPushedFcn = createCallbackFcn(app, @exportgraphsaspdfButtonPushed, true);
            app.exportgraphsaspdfButton.Text = 'export graphs as .pdf';
            app.loadindicatorsPanel = uipanel(app.UIFigure);
            app.loadindicatorsPanel.Title = 'load indicators';
            app.ImageBlueLine = uiimage(app.loadindicatorsPanel);
            app.ImageBlueLine.ImageSource = 'blueLine.png';
            app.ImageGreenLine = uiimage(app.loadindicatorsPanel);
            app.ImageGreenLine.ImageSource = 'greenLine.png';
            app.ImageMagentaLine = uiimage(app.loadindicatorsPanel);
            app.ImageMagentaLine.ImageSource = 'magentaLine.png';
            app.forcesLabel = uilabel(app.loadindicatorsPanel);
            app.forcesLabel.Text = 'forces';
            app.bendingmomentsLabel = uilabel(app.loadindicatorsPanel);
            app.bendingmomentsLabel.Text = 'bending moments';
            app.lineloadsLabel = uilabel(app.loadindicatorsPanel);
            app.lineloadsLabel.Text = 'line loads';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = results(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end