classdef CIFRIB_v1 < matlab.apps.AppBase
    %CIFRIB_V1 Program for the calculation of internal forces and reactions
    %inside of bars

    % Properties that correspond to app components
    properties (Access = public)
        CIFRIBUIFigure                 matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        Menu_New                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveAsMenu                     matlab.ui.container.Menu
        ExportMenu                     matlab.ui.container.Menu
        TxtMenu                        matlab.ui.container.Menu
        JPGMenu                        matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        Menu_Undo                      matlab.ui.container.Menu
        Menu_Redo                      matlab.ui.container.Menu
        PreferencesMenu                matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        GridLayout12                   matlab.ui.container.GridLayout
        TabGroup_MainGui               matlab.ui.container.TabGroup
        Tab_Setup                      matlab.ui.container.Tab
        GridLayout11                   matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        GridLayout13                   matlab.ui.container.GridLayout
        Panel_5                        matlab.ui.container.Panel
        GridLayout31                   matlab.ui.container.GridLayout
        Panel_9                        matlab.ui.container.Panel
        Panel_16                       matlab.ui.container.Panel
        UIAxes_Setup                   matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        DeleteViewButton               matlab.ui.control.StateButton
        Lamp_StaticDefinition          matlab.ui.control.Lamp
        AngledegEditField              matlab.ui.control.NumericEditField
        AngledegEditFieldLabel         matlab.ui.control.Label
        PositionmEditField             matlab.ui.control.NumericEditField
        PositionmEditFieldLabel        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ForceNNmEditField              matlab.ui.control.NumericEditField
        ForceNNmEditFieldLabel         matlab.ui.control.Label
        EditField_StaticDefinition2    matlab.ui.control.NumericEditField
        EditField_StaticDefinition1    matlab.ui.control.NumericEditField
        LeftPanel                      matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        BarSetupPanel                  matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        TabGroup3                      matlab.ui.container.TabGroup
        BarTab                         matlab.ui.container.Tab
        GridLayout18                   matlab.ui.container.GridLayout
        Button_BarAdd                  matlab.ui.control.Button
        Panel_6                        matlab.ui.container.Panel
        GridLayout19                   matlab.ui.container.GridLayout
        ProfileDropDown                matlab.ui.control.DropDown
        ProfileDropDownLabel           matlab.ui.control.Label
        MaterialDropDown               matlab.ui.control.DropDown
        MaterialDropDownLabel          matlab.ui.control.Label
        Spinner_BarLength              matlab.ui.control.Spinner
        LengthSpinnerLabel             matlab.ui.control.Label
        BarprofileTab                  matlab.ui.container.Tab
        Panel_Loads                    matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        TabGroupForce                  matlab.ui.container.TabGroup
        PointloadsTab                  matlab.ui.container.Tab
        GridLayout26                   matlab.ui.container.GridLayout
        Panel_13                       matlab.ui.container.Panel
        GridLayout28                   matlab.ui.container.GridLayout
        Button_PointloadsMoment        matlab.ui.control.StateButton
        Button_PointLoadsForce         matlab.ui.control.StateButton
        Panel_12                       matlab.ui.container.Panel
        GridLayout27                   matlab.ui.container.GridLayout
        Spinner_PointLoadsPosition     matlab.ui.control.Spinner
        PositionSpinner_3Label         matlab.ui.control.Label
        Spinner_PointLoadsForce        matlab.ui.control.Spinner
        ForceSpinnerLabel              matlab.ui.control.Label
        Spinner_PointLoadsAngle        matlab.ui.control.Spinner
        AngleSpinnerLabel              matlab.ui.control.Label
        Ignore_AngleSpinner            matlab.ui.control.Spinner
        Ignore_AngleSpinnerLabel       matlab.ui.control.Label
        Button_PointloadsAdd           matlab.ui.control.Button
        DistributedloadsTab            matlab.ui.container.Tab
        GridLayout26_2                 matlab.ui.container.GridLayout
        Panel_15                       matlab.ui.container.Panel
        GridLayout30                   matlab.ui.container.GridLayout
        Button_DistributedLoadsQuadratic  matlab.ui.control.StateButton
        Button_DistributedLoadsLinear  matlab.ui.control.StateButton
        Button_DistributedLoadsConstant  matlab.ui.control.StateButton
        Panel_14                       matlab.ui.container.Panel
        GridLayout29                   matlab.ui.container.GridLayout
        Spinner_DistributedLoadsStartPosition  matlab.ui.control.Spinner
        StartPositionSpinnerLabel      matlab.ui.control.Label
        Spinner_DistributedLoadsEndPosition  matlab.ui.control.Spinner
        EndPositionSpinnerLabel        matlab.ui.control.Label
        Spinner_DistributedLoadsStartValue  matlab.ui.control.Spinner
        StartValueSpinnerLabel         matlab.ui.control.Label
        Spinner_DistributedLoadsPitch  matlab.ui.control.Spinner
        PitchSpinnerLabel              matlab.ui.control.Label
        Button_DistributedLoadsAdd     matlab.ui.control.Button
        SupportsPanel                  matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        SupportTab                     matlab.ui.container.Tab
        GridLayout20                   matlab.ui.container.GridLayout
        Panel_8                        matlab.ui.container.Panel
        GridLayout21                   matlab.ui.container.GridLayout
        Spinner_SupportPosition        matlab.ui.control.Spinner
        PositionSpinnerLabel           matlab.ui.control.Label
        Panel_7                        matlab.ui.container.Panel
        GridLayout22                   matlab.ui.container.GridLayout
        Button_SupportClampedSupport   matlab.ui.control.StateButton
        Button_SupportFixedBearing     matlab.ui.control.StateButton
        Button_SupportLooseBearing     matlab.ui.control.StateButton
        Button_SupportAdd              matlab.ui.control.Button
        HingeTab                       matlab.ui.container.Tab
        GridLayout23                   matlab.ui.container.GridLayout
        Panel_11                       matlab.ui.container.Panel
        GridLayout25                   matlab.ui.container.GridLayout
        Spinner_HingePosition          matlab.ui.control.Spinner
        PositionSpinner_2Label         matlab.ui.control.Label
        Panel_10                       matlab.ui.container.Panel
        GridLayout32                   matlab.ui.container.GridLayout
        Button_HingeNormalforce        matlab.ui.control.StateButton
        Button_HingeShearforce         matlab.ui.control.StateButton
        Button_HingeMoment             matlab.ui.control.StateButton
        Button_HingeAdd                matlab.ui.control.Button
        Button_SetupCalculate          matlab.ui.control.Button
        Tab_Results                    matlab.ui.container.Tab
        GridLayout14                   matlab.ui.container.GridLayout
        Panel_4                        matlab.ui.container.Panel
        GridLayout16                   matlab.ui.container.GridLayout
        BearingReactionsPanel          matlab.ui.container.Panel
        GridLayout33                   matlab.ui.container.GridLayout
        UITable_Results                matlab.ui.control.Table
        CalculateForcesataspecificpointPanel  matlab.ui.container.Panel
        GridLayout17                   matlab.ui.container.GridLayout
        MomentEditField                matlab.ui.control.NumericEditField
        MomentEditFieldLabel           matlab.ui.control.Label
        ZForceEditField                matlab.ui.control.NumericEditField
        ZForceEditFieldLabel           matlab.ui.control.Label
        XForceEditField                matlab.ui.control.NumericEditField
        XForceEditFieldLabel           matlab.ui.control.Label
        PositionEditField              matlab.ui.control.NumericEditField
        PositionEditFieldLabel         matlab.ui.control.Label
        Panel_3                        matlab.ui.container.Panel
        GridLayout15                   matlab.ui.container.GridLayout
        UIAxes_ResultsMoment           matlab.ui.control.UIAxes
        UIAxes_ResultsShearForce       matlab.ui.control.UIAxes
        UIAxes_ResultsNormalForce      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            structureinitiate; % initiate the struct in which all values are written
        end

        % Callback function
        function CalculateButtonPushed(app, event)
            
        end

        % Value changed function: Spinner_BarLength
        function Spinner_BarLengthValueChanged(app, event)
            global lbar main;
            lbar = app.Spinner_BarLength.Value; % set length of bar when value is changed in edit field
            main.BarLgh = lbar;
        end

        % Button pushed function: Button_BarAdd
        function Button_BarAddPushed(app, event)
            % Check if the numeric field has a value
            if isempty(app.Spinner_BarLength.Value) || isnan(app.Spinner_BarLength.Value) || (app.Spinner_BarLength.Value <= 0)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'Please enter a value for Bar Length > 0', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end
            cla (app.UIAxes_Setup, 'reset');
            X = [0 app.Spinner_BarLength.Value];
            Y = [0 0];
            plot(app.UIAxes_Setup,X,Y,'LineWidth',4,'Color','k');
            hold(app.UIAxes_Setup,'on')
            app.EditField_StaticDefinition1.Value = 3;
            app.EditField_StaticDefinition2.Value = 0;
            %staticallyindeterminateValueChanged(app);
        end

        % Button pushed function: Button_SupportAdd
        function Button_SupportAddPushed(app, event)
            if isempty(any(app.Spinner_SupportPosition.Value)) || (any(app.Spinner_SupportPosition.Value > app.Spinner_BarLength.Value))
              return
            end

          % Fixed_Bearing
        if app.Button_SupportFixedBearing.Value == true
            % Plot
            bearing1_pos = app.Spinner_SupportPosition.Value;   % position of bearing1
            bearing1_angle = 0; % angle of bearing1
            bearing1hatchingshift = 0;
            bearing1scale = (app.Spinner_BarLength.Value/15);    % size of the bearing = 1/10 of bar length
            Bearing1X = [0 (1/2) (-1/2) 0];
            Bearing1Y = [0 (-1) (-1) 0];
            Bearing1BaseX = [-1 1];
            Bearing1BaseY = [-1 -1] + bearing1hatchingshift;
            Bearing1Hatching1X = [(-1/2) -1];
            Bearing1Hatching1Y = [-1 (-3/2)] + bearing1hatchingshift;
            Bearing1Hatching2X = [0 (-1/2)];
            Bearing1Hatching2Y = [-1 (-3/2)] + bearing1hatchingshift;
            Bearing1Hatching3X = [(1/2) 0];
            Bearing1Hatching3Y = [-1 (-3/2)] + bearing1hatchingshift;
            bearing1phi = deg2rad(bearing1_angle);
            BearingR1 = [cos(bearing1phi) -sin(bearing1phi); sin(bearing1phi) cos(bearing1phi)];
            Bearing1 = [Bearing1X NaN Bearing1BaseX NaN Bearing1Hatching1X NaN Bearing1Hatching2X NaN Bearing1Hatching3X; Bearing1Y NaN Bearing1BaseY NaN Bearing1Hatching1Y NaN Bearing1Hatching2Y NaN Bearing1Hatching3Y] * bearing1scale;
            Bearing1Rotated = BearingR1 * Bearing1;
            Bearing1XRotated = Bearing1Rotated(1,:) + bearing1_pos;
            Bearing1YRotated = Bearing1Rotated(2,:);
            plot(app.UIAxes_Setup,Bearing1XRotated,Bearing1YRotated,'LineWidth',2,'Color','k');   % plot bearing1
            grid(app.UIAxes_Setup,'on')
            axis(app.UIAxes_Setup,'equal')
            axis(app.UIAxes_Setup,'padded')
            hold(app.UIAxes_Setup,'on')

            % Static Definition
            app.EditField_StaticDefinition2.Value = app.EditField_StaticDefinition2.Value + 2;
            %staticallyindeterminateValueChanged(app);

            % Handover to main.Bearing
            AddBearing(bearing1_pos, 1, 1, 0);

        end

        % Loose_Bearing
        if app.Button_SupportLooseBearing.Value == true
            % Plot
            bearing2_pos = app.Spinner_SupportPosition.Value;   % position of bearing1
            bearing2_angle = 0; % angle of bearing1
            % define bearing2
            bearing2hatchingshift = (-1/4);
            bearing2scale = (app.Spinner_BarLength.Value/15);    % size of the bearing = 1/10 of bar length
            Bearing2X = [0 (1/2) (-1/2) 0];
            Bearing2Y = [0 (-1) (-1) 0];
            Bearing2BaseX = [-1 1];
            Bearing2BaseY = [-1 -1] + bearing2hatchingshift;
            Bearing2Hatching1X = [(-1/2) -1];
            Bearing2Hatching1Y = [-1 (-3/2)] + bearing2hatchingshift;
            Bearing2Hatching2X = [0 (-1/2)];
            Bearing2Hatching2Y = [-1 (-3/2)] + bearing2hatchingshift;
            Bearing2Hatching3X = [(1/2) 0];
            Bearing2Hatching3Y = [-1 (-3/2)] + bearing2hatchingshift;
            bearing2phi = deg2rad(bearing2_angle);
            BearingR2 = [cos(bearing2phi) -sin(bearing2phi); sin(bearing2phi) cos(bearing2phi)];
            Bearing2 = [Bearing2X NaN Bearing2BaseX NaN Bearing2Hatching1X NaN Bearing2Hatching2X NaN Bearing2Hatching3X; Bearing2Y NaN Bearing2BaseY NaN Bearing2Hatching1Y NaN Bearing2Hatching2Y NaN Bearing2Hatching3Y] * bearing2scale;
            Bearing2Rotated = BearingR2 * Bearing2;
            Bearing2XRotated = Bearing2Rotated(1,:) + bearing2_pos;
            Bearing2YRotated = Bearing2Rotated(2,:);
            plot(app.UIAxes_Setup, Bearing2XRotated,Bearing2YRotated,'LineWidth',2,'Color','k');   % plot bearing2
            grid(app.UIAxes_Setup,'on')
            axis(app.UIAxes_Setup,'equal')
            axis(app.UIAxes_Setup,'padded')
            hold(app.UIAxes_Setup,'on')

            % Static Definition
            app.EditField_StaticDefinition1.Value = app.EditField_StaticDefinition1.Value + 1;
            %staticallyindeterminateValueChanged(app);

            % Handover to main.Bearing
            AddBearing(bearing2_pos, 1, 0, 0);

        end

            % Clamped_Support
            if (app.Button_SupportClampedSupport.Value == true) &&  (app.Spinner_SupportPosition.Value == 0)
                % Plot
                clampedsupportscale = app.Spinner_BarLength.Value/15;    % size of the support = 1/10 of bar length
                ClampedSupportX = [0 0 0];
                ClampedSupportY = [0 1 -1];
                ClampedSupportHatching1X = [0 (-1/2)];
                ClampedSupportHatching1Y = [0 (-1/2)] + 1;
                ClampedSupportHatching2X = [0 (-1/2)];
                ClampedSupportHatching2Y = [0 (-1/2)] + (1/2);
                ClampedSupportHatching3X = [0 (-1/2)];
                ClampedSupportHatching3Y = [0 (-1/2)];
                ClampedSupportHatching4X = [0 (-1/2)];
                ClampedSupportHatching4Y = [0 (-1/2)] + (-1/2);
                ClampedSupport = [...
        ClampedSupportX NaN ClampedSupportHatching1X NaN ClampedSupportHatching2X NaN ClampedSupportHatching3X NaN ClampedSupportHatching4X; 
        ClampedSupportY NaN ClampedSupportHatching1Y NaN ClampedSupportHatching2Y NaN ClampedSupportHatching3Y NaN ClampedSupportHatching4Y] ...
        * clampedsupportscale;
                ClampedSupportXRotated = ClampedSupport(1,:) + app.Spinner_SupportPosition.Value;
                ClampedSupportYRotated = ClampedSupport(2,:);
                plot(app.UIAxes_Setup,ClampedSupportXRotated,ClampedSupportYRotated,'LineWidth',2,'Color','k');   % plot bearing2
                grid(app.UIAxes_Setup,'on')
                axis(app.UIAxes_Setup,'equal')
                axis(app.UIAxes_Setup,'padded')
                hold(app.UIAxes_Setup,'on')

                % Static Definition
                app.EditField_StaticDefinition1.Value = app.EditField_StaticDefinition1.Value + 3;
                %staticallyindeterminateValueChanged(app);
 
                % Handover to main.Bearing
                AddBearing(0, 1, 1, 1);

            end

            % if strcmp(selectedItem, 'Fixed') && (app.PositionmEditField_2.Value == app.barlengthmEditField.Value)
            %     %Graphische Darstellung
            %     clampedsupportscale = (app.barlengthmEditField.Value/15);    % size of the support = 1/10 of bar length
            %     support_position = app.PositionmEditField_2.Value;
            % 
            %     ClampedSupportX = [0 0 0] + support_position;
            %     ClampedSupportY = [0 1 -1];
            %     ClampedSupportHatching1X = [0 (1/2)] + support_position;
            %     ClampedSupportHatching1Y = [0 (-1/2)] + 1;
            %     ClampedSupportHatching2X = [0 (1/2)] + support_position;
            %     ClampedSupportHatching2Y = [0 (-1/2)] + (1/2);
            %     ClampedSupportHatching3X = [0 (1/2)] + support_position;
            %     ClampedSupportHatching3Y = [0 (-1/2)];
            %     ClampedSupportHatching4X = [0 (1/2)] + support_position;
            %     ClampedSupportHatching4Y = [0 (-1/2)] + (-1/2);
            %     ClampedSupport = [...
            %     ClampedSupportX NaN ClampedSupportHatching1X NaN ClampedSupportHatching2X NaN ClampedSupportHatching3X NaN ClampedSupportHatching4X; 
            %     ClampedSupportY NaN ClampedSupportHatching1Y NaN ClampedSupportHatching2Y NaN ClampedSupportHatching3Y NaN ClampedSupportHatching4Y] ...
            %     * clampedsupportscale;
            %     ClampedSupportXRotated = ClampedSupport(1,:) + app.PositionmEditField_2.Value;
            %     ClampedSupportYRotated = ClampedSupport(2,:);
            %     plot(app.UIAxes_ResultsNormalForce,ClampedSupportXRotated,ClampedSupportYRotated,'LineWidth',2,'Color','k');   % plot bearing2
            %     grid(app.UIAxes_ResultsNormalForce,'on')
            %     axis(app.UIAxes_ResultsNormalForce,'equal')
            %     axis(app.UIAxes_ResultsNormalForce,'padded')
            %     hold(app.UIAxes_ResultsNormalForce,'on')
            % 
            %     %Statische Bestimmtheit
            %     app.EditField_StaticDefinition1.Value = app.EditField_StaticDefinition1.Value + 3;
            %     staticallyindeterminateValueChanged(app);
            % 
            % end

            
        end

        % Button pushed function: Button_PointloadsAdd
        function Button_PointloadsAddPushed(app, event)
            %Error Messages 
            if isempty(app.Spinner_PointLoadsForce.Value) || isnan(app.Spinner_PointLoadsForce.Value) || (app.Spinner_PointLoadsForce.Value == 0)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'Please enter a value for Force.', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end
            if isempty(app.Spinner_PointLoadsPosition.Value) || isnan(app.Spinner_PointLoadsPosition.Value) || (app.Spinner_PointLoadsPosition.Value < 0) || (app.Spinner_PointLoadsPosition.Value > app.Spinner_BarLength.Value)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'The Position is outside the bar. Please enter a valid value.', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end


            % Add Force
        if app.Button_PointLoadsForce.Value == true

            % Check if input is resonabile
            if any(app.Spinner_PointLoadsForce.Value == 0) || isempty(app.Spinner_PointLoadsForce.Value) || any(app.Spinner_PointLoadsPosition.Value > app.Spinner_BarLength.Value) || any(app.Spinner_PointLoadsPosition.Value < 0)
                return
            else
                % Plot Force(function) aber erst nach add force aufruf
                global main;
                main.DelInf = [];
               % Plot
                arrow2_angle = app.Spinner_PointLoadsAngle.Value;    % angle of arrow2
                arrow2_pos = app.Spinner_PointLoadsPosition.Value; % position of arrow2
                
                % define arrow2
                arrow2_length = 2;
                arrow2scale = ((app.Spinner_BarLength.Value/arrow2_length)/10);    % length of the arrow = 1/10 of bar length
                arrow2phi = deg2rad(arrow2_angle-180);
                TextArrow2X = (-6/4) * arrow2_length;
                TextArrow2Y = (-2/4) * arrow2_length;
                Line2X = [(-4/4) (-1/4)] * arrow2_length;    % define the line of arrow2
                Line2Y = [0 0];  % define the line of arrow2
                Arrow2X = [(-1/4) (-1/4) 0 (-1/4) (-1/4)];   % define the tip of arrow2
                Arrow2Y = [0 (1/4) 0 (-1/4) 0];  % define the tip of arrow2
                Arrow2R = [cos(arrow2phi) -sin(arrow2phi); sin(arrow2phi) cos(arrow2phi)];
                Arrow2 = [TextArrow2X Line2X Arrow2X; TextArrow2Y Line2Y Arrow2Y] * arrow2scale;
                Arrow2Rotated = Arrow2R * Arrow2;
                Arrow2XRotated = Arrow2Rotated(1,:) + arrow2_pos;
                Arrow2YRotated = Arrow2Rotated(2,:);
                plot(app.UIAxes_Setup,Arrow2XRotated(2:8),Arrow2YRotated(2:8),'LineWidth',2,'Color','r');   % plot arrow2
                text(app.UIAxes_Setup,Arrow2XRotated(1),Arrow2YRotated(1),"F = "+ app.Spinner_PointLoadsForce.Value + " N",'Fontsize',15,'Color','r');   % name arrow2
                grid(app.UIAxes_Setup,'on')
                axis(app.UIAxes_Setup,'equal')
                axis(app.UIAxes_Setup,'padded')
                
                %Aufrufen der Funktion
                AddForce(app.Spinner_PointLoadsPosition.Value,app.Spinner_PointLoadsAngle.Value,app.Spinner_PointLoadsForce.Value);
            end
        end

        % Add Tourque

        
        if app.Button_PointloadsMoment.Value == true

            %Moment
            % Check if input is resonabile
            if any(app.Spinner_PointLoadsForce.Value == 0) || isempty(app.Spinner_PointLoadsForce.Value)
                return
            else
            % Plot
            moment1_pos = app.Spinner_PointLoadsPosition.Value;
            moment1scale = (app.Spinner_BarLength.Value/30);   % size of the joint = 1/30 of bar length
            moment1_sign = app.Spinner_PointLoadsForce.Value;
            moment1_direction = sign(moment1_sign);
            Moment1X = [0 NaN 1 (0.866) (0.5) 0 NaN (-1) (-0.866) (-0.5) 0 (0.5) (0.866) 1];
            Moment1Y = [0 NaN 0 (0.5) (0.866) 1 NaN 0 (-0.5) (-0.866) (-1) (-0.866) (-0.5) 0];
            MomentArrow1X = [(1/4) (1/4) 0 (1/4) (1/4)];   % define the tip of moment1
            MomentArrow1Y = [0 (1/4) 0 (-1/4) 0];  % define the tip of moment1
            momentarrow1phi = deg2rad(-15);
            MomentArrow1R = [cos(momentarrow1phi) -sin(momentarrow1phi); sin(momentarrow1phi) cos(momentarrow1phi)];
            MomentArrow1 = [MomentArrow1X; MomentArrow1Y];
            MomentArrow1Rotated = MomentArrow1R * MomentArrow1;
            TextMoment1X = 1 + (1/2);
            TextMoment1Y = 1 + (1/2);
            Moment1 = [TextMoment1X NaN Moment1X NaN MomentArrow1Rotated(1,:); TextMoment1Y NaN Moment1Y NaN MomentArrow1Rotated(2,:)+1] * moment1scale;
            Moment1XRotated = (Moment1(1,:)*moment1_direction) + moment1_pos;
            Moment1YRotated = Moment1(2,:);
            plot(app.UIAxes_Setup, Moment1XRotated(2:22),Moment1YRotated(2:22),'LineWidth',2,'Color','green');
            text(app.UIAxes_Setup, Moment1XRotated(1),Moment1YRotated(1),strcat("M = ", num2str(app.Spinner_PointLoadsForce.Value), " Nm"),'Fontsize',15,'Color',"green");   % name moment1
            grid(app.UIAxes_Setup,'on')
            axis(app.UIAxes_Setup,'equal')
            axis(app.UIAxes_Setup,'padded')

            %Übergabe in main-Structure
            AddTorque(app.Spinner_PointLoadsPosition.Value,app.Spinner_PointLoadsForce.Value);
            end

        end
        end

        % Button pushed function: Button_DistributedLoadsAdd
        function Button_DistributedLoadsAddPushed(app, event)
            %Error Messages 
            if isempty(app.Spinner_DistributedLoadsStartPosition.Value) || isnan(app.Spinner_DistributedLoadsStartPosition.Value) || (app.Spinner_DistributedLoadsStartPosition.Value >= app.Spinner_BarLength.Value) || (app.Spinner_DistributedLoadsStartPosition.Value >= app.Spinner_DistributedLoadsEndPosition.Value)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'Please enter a valid Start Position.', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end
            if isempty(app.Spinner_DistributedLoadsEndPosition.Value) || isnan(app.Spinner_DistributedLoadsEndPosition.Value) || (app.Spinner_DistributedLoadsEndPosition.Value > app.Spinner_BarLength.Value) || (app.Spinner_DistributedLoadsEndPosition.Value < app.Spinner_DistributedLoadsStartPosition.Value)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'Please enter a valid End Value.', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end
            if isempty(app.Spinner_DistributedLoadsStartValue.Value) || isnan(app.Spinner_DistributedLoadsStartValue.Value)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'Please enter a Start Value.', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end
            if isempty(app.Spinner_DistributedLoadsPitch.Value) || isnan(app.Spinner_DistributedLoadsPitch.Value)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'Please enter a Pitch.', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end


            % Check if input is resonabile
            if isempty(app.Spinner_DistributedLoadsStartValue.Value) || isempty(app.Spinner_DistributedLoadsStartPosition.Value) || isempty(app.Spinner_DistributedLoadsEndPosition.Value) || (app.Spinner_DistributedLoadsStartPosition.Value > app.Spinner_BarLength.Value) || (app.Spinner_DistributedLoadsEndPosition.Value > app.Spinner_BarLength.Value) || (app.Spinner_DistributedLoadsStartPosition.Value < 0) || (app.Spinner_DistributedLoadsEndPosition.Value < 0)
            return
            end

            global main;
            main.DelInf = [];
          if app.Button_DistributedLoadsConstant.Value == true
              constshape1exponent = 0;
          elseif app.Button_DistributedLoadsLinear.Value == true
              constshape1exponent = 1;
          elseif app.Button_DistributedLoadsQuadratic.Value == true
              constshape1exponent = 2;
          end
        % 
        % constshape1_startpos = app.Spinner_DistributedLoadsStartPosition.Value;    % start position of constshape1
        % constshape1_endpos = app.Spinner_DistributedLoadsEndPosition.Value;   % end position of constshape1
        % 
        % % Distributed Load
        % % define constshape1
        % constshape1scale = (app.Spinner_BarLength.Value/2); %(1 / bar1length * (constshape1_endpos - constshape1_startpos));    % size of the shape = 1/10 of bar length 
        % constshape1direction = sign(app.Spinner_DistributedLoadsPitch.Value);   % 1 or -1 sign(x)
        % constshape1_startheight = sign(app.Spinner_DistributedLoadsStartValue.Value);
        % %constshape1_endheight = ((constshape1_endpos - constshape1_startpos)^1) * constshape1_startpos;
        % ConstShape1XBetween = linspace(constshape1_startpos,constshape1_endpos,10);
        % ConstShape1YBetween = ((ConstShape1XBetween - constshape1_startpos) .^ constshape1exponent);                                          %linspace(0,((constshape1_endpos - constshape1_startpos)^1) * constshape1_startpos);
        % ConstShape1X = [constshape1_startpos ConstShape1XBetween constshape1_endpos];
        % ConstShape1Y = [0 (constshape1direction*(ConstShape1YBetween./ConstShape1YBetween(10:10)) + constshape1_startheight) 0];
        % TextConstShape1X = constshape1_endpos + (1/2);
        % TextConstShape1Y = ConstShape1Y(12:12) + (1/2);
        % % constshape1phi = deg2rad(constshape1_angle-90);
        % % ConstShape1R = [cos(constshape1phi) -sin(constshape1phi); sin(constshape1phi) cos(constshape1phi)];
        % ConstShape1 = [TextConstShape1X NaN ConstShape1X; TextConstShape1Y NaN ConstShape1Y];
        % ConstShape1Rotated = ConstShape1; %ConstShape1R * ConstShape1;
        % ConstShape1XRotated = ConstShape1Rotated(1,:);
        % ConstShape1YRotated = ConstShape1Rotated(2,:) * constshape1scale * (1 / app.Spinner_BarLength.Value * (constshape1_endpos - constshape1_startpos));
        % plot(app.UIAxes_Setup, ConstShape1XRotated(2:14),ConstShape1YRotated(2:14),'LineWidth',2,'Color',"blue");   % plot constshape1
        % text(app.UIAxes_Setup, ConstShape1XRotated(1),ConstShape1YRotated(1),strcat("q = ", num2str(app.Spinner_DistributedLoadsStartValue.Value),"+", num2str(app.Spinner_DistributedLoadsPitch.Value), '*x^{' , num2str(constshape1exponent) , '}'," N/m" ),'Fontsize',15,'Color',"blue");   % name constshape1
        % 
        % 
        % grid(app.UIAxes_Setup,'on')
        % axis(app.UIAxes_Setup,'equal')
        % axis(app.UIAxes_Setup,'padded')

        AddDistl(app.Spinner_DistributedLoadsStartPosition.Value,app.Spinner_DistributedLoadsEndPosition.Value,app.Spinner_DistributedLoadsStartValue.Value,1,constshape1exponent,app.Spinner_DistributedLoadsPitch.Value);
        PlotAllSetup(app,constshape1exponent);
       
        end

        % Button pushed function: Button_SetupCalculate
        function Button_SetupCalculatePushed(app, event)
            global results;
            calcformulas(app.Spinner_BarLength.Value);
            app.TabGroup_MainGui.SelectedTab = app.Tab_Results;
            fplot(app.UIAxes_ResultsNormalForce, results.Fx, [0,app.Spinner_BarLength.Value]);
            fplot(app.UIAxes_ResultsMoment, results.Mb, [0,app.Spinner_BarLength.Value]);
            fplot(app.UIAxes_ResultsShearForce, results.Fz, [0,app.Spinner_BarLength.Value]);
            app.UITable_Results.Data = results.BearingForces;
        end

        % Menu selected function: Menu_New
        function Menu_NewSelected(app, event)
            cla(app.UIAxes_Setup)
        end

        % Button pushed function: Button_HingeAdd
        function Button_HingeAddPushed(app, event)
            if isempty(app.Spinner_HingePosition.Value) || isnan(app.Spinner_HingePosition.Value)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'Please enter a valid value.', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end
            if (app.Spinner_HingePosition.Value <= 0) || (app.Spinner_HingePosition.Value > app.Spinner_BarLength.Value)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'The Position is outside the bar. Please enter a valid value.', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end

            % MomentHinge
            if app.Button_HingeMoment.Value == true
                % Plot
                momentjoint1_pos = app.Spinner_HingePosition.Value; 
                momentjoint1scale = (app.Spinner_BarLength.Value/60);   % size of the joint = 1/60 of bar length
                MomentJoint1X = [0 1 (0.866) (0.5) 0 (-0.5) (-0.866) (-1) (-0.866) (-0.5) 0 (0.5) (0.866) 1] * momentjoint1scale;
                MomentJoint1Y = [0 0 (0.5) (0.866) 1 (0.866) (0.5) 0 (-0.5) (-0.866) (-1) (-0.866) (-0.5) 0] * momentjoint1scale;
                MomentJoint1XRotated = MomentJoint1X + momentjoint1_pos;
                MomentJoint1YRotated = MomentJoint1Y;
                plot(app.UIAxes_Setup, MomentJoint1XRotated(2:14),MomentJoint1YRotated(2:14),'LineWidth',2,'Color','k');   % plot momentjoint1
                grid(app.UIAxes_Setup,'on')
                axis(app.UIAxes_Setup,'equal')
                axis(app.UIAxes_Setup,'padded')
                hold(app.UIAxes_Setup,'on')

                % Static Definition
                app.EditField_StaticDefinition1.Value = app.EditField_StaticDefinition1.Value + 2;
                app.EditField_StaticDefinition2.Value = app.EditField_StaticDefinition2.Value + 3;
                %staticallyindeterminateValueChanged(app);

                % Handover to the structure
                AddJoint(momentjoint1_pos, 1, 1, 0)

            end

           if app.Button_HingeNormalforce.Value == true
            % Plot
            momentjoint1_pos = app.Spinner_HingePosition.Value; 
            momentjoint1scale = (app.Spinner_BarLength.Value/60);   % size of the joint = 1/60 of bar length
            MomentJoint1X = [0 1 (0.866) (0.5) 0 (-0.5) (-0.866) (-1) (-0.866) (-0.5) 0 (0.5) (0.866) 1] * momentjoint1scale;
            MomentJoint1Y = [0 0 (0.5) (0.866) 1 (0.866) (0.5) 0 (-0.5) (-0.866) (-1) (-0.866) (-0.5) 0] * momentjoint1scale;
            MomentJoint1XRotated = MomentJoint1X + momentjoint1_pos;
            MomentJoint1YRotated = MomentJoint1Y;
            plot(app.UIAxes_Setup, MomentJoint1XRotated(2:14),MomentJoint1YRotated(2:14),'LineWidth',2,'Color','k');   % plot momentjoint1
            grid(app.UIAxes_Setup,'on')
            axis(app.UIAxes_Setup,'equal')
            axis(app.UIAxes_Setup,'padded')
            hold(app.UIAxes_Setup,'on')

            % Static Definition
            app.EditField_StaticDefinition1.Value = app.EditField_StaticDefinition1.Value + 2;
            app.EditField_StaticDefinition2.Value = app.EditField_StaticDefinition2.Value + 3;
            %staticallyindeterminateValueChanged(app);

            % Handover to the structure
            AddJoint(momentjoint1_pos, 0, 1, 1)

           end

           if app.Button_HingeShearforce.Value == true
            % Plot
            momentjoint1_pos = app.PositionmEditField_2.Value; 
            momentjoint1scale = (app.barlengthmEditField.Value/60);   % size of the joint = 1/60 of bar length
            MomentJoint1X = [0 1 (0.866) (0.5) 0 (-0.5) (-0.866) (-1) (-0.866) (-0.5) 0 (0.5) (0.866) 1] * momentjoint1scale;
            MomentJoint1Y = [0 0 (0.5) (0.866) 1 (0.866) (0.5) 0 (-0.5) (-0.866) (-1) (-0.866) (-0.5) 0] * momentjoint1scale;
            MomentJoint1XRotated = MomentJoint1X + momentjoint1_pos;
            MomentJoint1YRotated = MomentJoint1Y;
            plot(app.UIAxes_Setup, MomentJoint1XRotated(2:14),MomentJoint1YRotated(2:14),'LineWidth',2,'Color','k');   % plot momentjoint1
            grid(app.UIAxes_Setup,'on')
            axis(app.UIAxes_Setup,'equal')
            axis(app.UIAxes_Setup,'padded')
            hold(app.UIAxes_Setup,'on')

            % Static Definition
            app.EditField_StaticDefinition1.Value = app.EditField_StaticDefinition1.Value + 2;
            app.EditField_StaticDefinition2.Value = app.EditField_StaticDefinition2.Value + 3;
            %staticallyindeterminateValueChanged(app);

            % Handover to the structure
            AddJoint(momentjoint1_pos, 1, 0, 1)
           end
        end

        % Menu selected function: Menu_Undo
        function Menu_UndoSelected(app, event)
            StepBack;
        end

        % Menu selected function: Menu_Redo
        function Menu_RedoSelected(app, event)
            StepForward;
        end

        % Value changed function: PositionEditField
        function PositionEditFieldValueChanged(app, event)
            global results;
            syms x;
            value = app.PositionEditField.Value;
            app.MomentEditField.Value = eval(subs(results.Mb,x,app.PositionEditField.Value));
            app.ZForceEditField.Value = eval(subs(results.Fz,x,app.PositionEditField.Value));
            app.XForceEditField.Value = eval(subs(results.Fx,x,app.PositionEditField.Value));
        end

        % Value changed function: Button_HingeMoment
        function Button_HingeMomentValueChanged(app, event)
            %value = app.Button_HingeMoment.Value;
            app.Button_HingeNormalforce.Value = false;
            app.Button_HingeShearforce.Value = false;
            
        end

        % Value changed function: Button_HingeShearforce
        function Button_HingeShearforceValueChanged(app, event)
            %value = app.Button_HingeShearforce.Value;
            app.Button_HingeNormalforce.Value = false;
            app.Button_HingeMoment.Value = false;
        end

        % Value changed function: Button_HingeNormalforce
        function Button_HingeNormalforceValueChanged(app, event)
            %value = app.Button_HingeNormalforce.Value;
            app.Button_HingeMoment.Value = false;
            app.Button_HingeShearforce.Value = false;
        end

        % Value changed function: Button_SupportLooseBearing
        function Button_SupportLooseBearingValueChanged(app, event)
            %value = app.Button_SupportLooseBearing.Value;
            app.Button_SupportClampedSupport.Value = false;
            app.Button_SupportFixedBearing.Value = false;
        end

        % Value changed function: Button_SupportFixedBearing
        function Button_SupportFixedBearingValueChanged(app, event)
            %value = app.Button_SupportFixedBearing.Value;
            app.Button_SupportClampedSupport.Value = false;
            app.Button_SupportLooseBearing.Value = false;
        end

        % Value changed function: Button_SupportClampedSupport
        function Button_SupportClampedSupportValueChanged(app, event)
            %value = app.Button_SupportClampedSupport.Value;
            app.Button_SupportLooseBearing.Value = false;
            app.Button_SupportFixedBearing.Value = false;
        end

        % Value changed function: Button_PointLoadsForce
        function Button_PointLoadsForceValueChanged(app, event)
            %value = app.Button_PointLoadsForce.Value;
            app.Button_PointloadsMoment.Value = false;
        end

        % Value changed function: Button_PointloadsMoment
        function Button_PointloadsMomentValueChanged(app, event)
            %value = app.Button_PointloadsMoment.Value;
            app.Button_PointLoadsForce.Value = false;
        end

        % Value changed function: Button_DistributedLoadsConstant
        function Button_DistributedLoadsConstantValueChanged(app, event)
            %value = app.Button_DistributedLoadsConstant.Value;
            app.Button_DistributedLoadsLinear.Value = false;
            app.Button_DistributedLoadsQuadratic.Value = false;
        end

        % Value changed function: Button_DistributedLoadsLinear
        function Button_DistributedLoadsLinearValueChanged(app, event)
            %value = app.Button_DistributedLoadsLinear.Value;
            app.Button_DistributedLoadsConstant.Value = false;
            app.Button_DistributedLoadsQuadratic.Value = false;
        end

        % Value changed function: Button_DistributedLoadsQuadratic
        function Button_DistributedLoadsQuadraticValueChanged(app, event)
            %value = app.Button_DistributedLoadsQuadratic.Value;
            app.Button_DistributedLoadsLinear.Value = false;
            app.Button_DistributedLoadsConstant.Value = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CIFRIBUIFigure = uifigure('Visible', 'off');
            app.CIFRIBUIFigure.Name = 'CIFRIB';
            app.FileMenu = uimenu(app.CIFRIBUIFigure);
            app.FileMenu.Text = 'File';
            app.Menu_New = uimenu(app.FileMenu);
            app.Menu_New.MenuSelectedFcn = createCallbackFcn(app, @Menu_NewSelected, true);
            app.Menu_New.Text = 'New...';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As...';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.TxtMenu = uimenu(app.ExportMenu);
            app.TxtMenu.Text = 'Txt';
            app.JPGMenu = uimenu(app.ExportMenu);
            app.JPGMenu.Text = 'JPG';
            app.Menu = uimenu(app.FileMenu);
            app.Menu.Text = 'Menu';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.CIFRIBUIFigure);
            app.EditMenu.Text = 'Edit';
            app.Menu_Undo = uimenu(app.EditMenu);
            app.Menu_Undo.MenuSelectedFcn = createCallbackFcn(app, @Menu_UndoSelected, true);
            app.Menu_Undo.Text = 'Undo';
            app.Menu_Redo = uimenu(app.EditMenu);
            app.Menu_Redo.MenuSelectedFcn = createCallbackFcn(app, @Menu_RedoSelected, true);
            app.Menu_Redo.Text = 'Redo';
            app.PreferencesMenu = uimenu(app.EditMenu);
            app.PreferencesMenu.Text = 'Preferences';
            app.HelpMenu = uimenu(app.CIFRIBUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.CIFRIBUIFigure);
            app.AboutMenu.Text = 'About';
            app.GridLayout12 = uigridlayout(app.CIFRIBUIFigure);
            app.TabGroup_MainGui = uitabgroup(app.GridLayout12);
            app.Tab_Setup = uitab(app.TabGroup_MainGui);
            app.Tab_Setup.Title = 'Setup';
            app.GridLayout11 = uigridlayout(app.Tab_Setup);
            app.LeftPanel = uipanel(app.GridLayout11);
            app.GridLayout5 = uigridlayout(app.LeftPanel);
            app.Button_SetupCalculate = uibutton(app.GridLayout5, 'push');
            app.Button_SetupCalculate.ButtonPushedFcn = createCallbackFcn(app, @Button_SetupCalculatePushed, true);
            app.Button_SetupCalculate.Text = 'Calculate!';
            app.SupportsPanel = uipanel(app.GridLayout5);
            app.SupportsPanel.Title = 'Supports';
            app.GridLayout8 = uigridlayout(app.SupportsPanel);
            app.TabGroup2 = uitabgroup(app.GridLayout8);
            app.SupportTab = uitab(app.TabGroup2);
            app.SupportTab.Title = 'Support';
            app.GridLayout20 = uigridlayout(app.SupportTab);
            app.Button_SupportAdd = uibutton(app.GridLayout20, 'push');
            app.Button_SupportAdd.ButtonPushedFcn = createCallbackFcn(app, @Button_SupportAddPushed, true);
            app.Button_SupportAdd.Text = 'Add';
            app.Panel_7 = uipanel(app.GridLayout20);
            app.GridLayout22 = uigridlayout(app.Panel_7);
            app.Button_SupportLooseBearing = uibutton(app.GridLayout22, 'state');
            app.Button_SupportLooseBearing.ValueChangedFcn = createCallbackFcn(app, @Button_SupportLooseBearingValueChanged, true);
            app.Button_SupportLooseBearing.Icon = 'C:\Users\ichfr\OneDrive - FH JOANNEUM\Dokumente\Work\Technisches Programmieren Projekt\MATLAB\V4\loose_bearing.png';
            app.Button_SupportLooseBearing.Text = '';
            app.Button_SupportFixedBearing = uibutton(app.GridLayout22, 'state');
            app.Button_SupportFixedBearing.ValueChangedFcn = createCallbackFcn(app, @Button_SupportFixedBearingValueChanged, true);
            app.Button_SupportFixedBearing.Icon = 'C:\Users\ichfr\OneDrive - FH JOANNEUM\Dokumente\Work\Technisches Programmieren Projekt\MATLAB\V4\fixed_bearing.png';
            app.Button_SupportFixedBearing.Text = '';
            app.Button_SupportClampedSupport = uibutton(app.GridLayout22, 'state');
            app.Button_SupportClampedSupport.ValueChangedFcn = createCallbackFcn(app, @Button_SupportClampedSupportValueChanged, true);
            app.Button_SupportClampedSupport.Icon = 'C:\Users\ichfr\OneDrive - FH JOANNEUM\Dokumente\Work\Technisches Programmieren Projekt\MATLAB\V4\clamped_support.png';
            app.Button_SupportClampedSupport.Text = '';
            app.Panel_8 = uipanel(app.GridLayout20);
            app.GridLayout21 = uigridlayout(app.Panel_8);
            app.PositionSpinnerLabel = uilabel(app.GridLayout21);
            app.PositionSpinnerLabel.Text = 'Position';
            app.Spinner_SupportPosition = uispinner(app.GridLayout21);
            app.Spinner_SupportPosition.ValueDisplayFormat = '%11.4g m';
            app.HingeTab = uitab(app.TabGroup2);
            app.HingeTab.Title = 'Hinge';
            app.GridLayout23 = uigridlayout(app.HingeTab);
            app.Button_HingeAdd = uibutton(app.GridLayout23, 'push');
            app.Button_HingeAdd.ButtonPushedFcn = createCallbackFcn(app, @Button_HingeAddPushed, true);
            app.Button_HingeAdd.Text = 'Add';
            app.Panel_10 = uipanel(app.GridLayout23);
            app.GridLayout32 = uigridlayout(app.Panel_10);
            app.Button_HingeMoment = uibutton(app.GridLayout32, 'state');
            app.Button_HingeMoment.ValueChangedFcn = createCallbackFcn(app, @Button_HingeMomentValueChanged, true);
            app.Button_HingeMoment.Icon = 'C:\Users\ichfr\OneDrive - FH JOANNEUM\Dokumente\Work\Technisches Programmieren Projekt\MATLAB\V4\hinge.png';
            app.Button_HingeMoment.Text = '';
            app.Button_HingeShearforce = uibutton(app.GridLayout32, 'state');
            app.Button_HingeShearforce.ValueChangedFcn = createCallbackFcn(app, @Button_HingeShearforceValueChanged, true);
            app.Button_HingeShearforce.Icon = 'C:\Users\ichfr\OneDrive - FH JOANNEUM\Dokumente\Work\Technisches Programmieren Projekt\MATLAB\V4\shearforcehinge.png';
            app.Button_HingeShearforce.Text = '';
            app.Button_HingeNormalforce = uibutton(app.GridLayout32, 'state');
            app.Button_HingeNormalforce.ValueChangedFcn = createCallbackFcn(app, @Button_HingeNormalforceValueChanged, true);
            app.Button_HingeNormalforce.Icon = 'C:\Users\ichfr\OneDrive - FH JOANNEUM\Dokumente\Work\Technisches Programmieren Projekt\MATLAB\V4\normalforcehinge.png';
            app.Button_HingeNormalforce.Text = '';
            app.Panel_11 = uipanel(app.GridLayout23);
            app.GridLayout25 = uigridlayout(app.Panel_11);
            app.PositionSpinner_2Label = uilabel(app.GridLayout25);
            app.PositionSpinner_2Label.Text = 'Position';
            app.Spinner_HingePosition = uispinner(app.GridLayout25);
            app.Spinner_HingePosition.ValueDisplayFormat = '%11.4g m';
            app.Panel_Loads = uipanel(app.GridLayout5);
            app.Panel_Loads.Title = 'Loads';
            app.GridLayout9 = uigridlayout(app.Panel_Loads);
            app.TabGroupForce = uitabgroup(app.GridLayout9);
            app.PointloadsTab = uitab(app.TabGroupForce);
            app.PointloadsTab.Title = 'Point loads';
            app.GridLayout26 = uigridlayout(app.PointloadsTab);
            app.Button_PointloadsAdd = uibutton(app.GridLayout26, 'push');
            app.Button_PointloadsAdd.ButtonPushedFcn = createCallbackFcn(app, @Button_PointloadsAddPushed, true);
            app.Button_PointloadsAdd.Text = 'Add';
            app.Panel_12 = uipanel(app.GridLayout26);
            app.GridLayout27 = uigridlayout(app.Panel_12);
            app.Ignore_AngleSpinnerLabel = uilabel(app.GridLayout27);
            app.Ignore_AngleSpinnerLabel.Text = 'Ignore_Angle';
            app.Ignore_AngleSpinner = uispinner(app.GridLayout27);
            app.AngleSpinnerLabel = uilabel(app.GridLayout27);
            app.AngleSpinnerLabel.Text = 'Angle';
            app.Spinner_PointLoadsAngle = uispinner(app.GridLayout27);
            app.Spinner_PointLoadsAngle.ValueDisplayFormat = '%11.4g °';
            app.Spinner_PointLoadsAngle.Tooltip = {'0° = <-'};
            app.Spinner_PointLoadsAngle.Value = 90;
            app.ForceSpinnerLabel = uilabel(app.GridLayout27);
            app.ForceSpinnerLabel.Text = 'Force';
            app.Spinner_PointLoadsForce = uispinner(app.GridLayout27);
            app.Spinner_PointLoadsForce.ValueDisplayFormat = '%11.4g N';
            app.Spinner_PointLoadsForce.Tooltip = {''};
            app.PositionSpinner_3Label = uilabel(app.GridLayout27);
            app.PositionSpinner_3Label.Text = 'Position';
            app.Spinner_PointLoadsPosition = uispinner(app.GridLayout27);
            app.Spinner_PointLoadsPosition.ValueDisplayFormat = '%11.4g m';
            app.Panel_13 = uipanel(app.GridLayout26);
            app.GridLayout28 = uigridlayout(app.Panel_13);
            app.Button_PointLoadsForce = uibutton(app.GridLayout28, 'state');
            app.Button_PointLoadsForce.ValueChangedFcn = createCallbackFcn(app, @Button_PointLoadsForceValueChanged, true);
            app.Button_PointLoadsForce.Icon = 'C:\Users\ichfr\OneDrive - FH JOANNEUM\Dokumente\Work\Technisches Programmieren Projekt\MATLAB\V4\force.png';
            app.Button_PointLoadsForce.Text = '';
            app.Button_PointloadsMoment = uibutton(app.GridLayout28, 'state');
            app.Button_PointloadsMoment.ValueChangedFcn = createCallbackFcn(app, @Button_PointloadsMomentValueChanged, true);
            app.Button_PointloadsMoment.Icon = 'C:\Users\ichfr\OneDrive - FH JOANNEUM\Dokumente\Work\Technisches Programmieren Projekt\MATLAB\V4\moment.png';
            app.Button_PointloadsMoment.Text = '';
            app.DistributedloadsTab = uitab(app.TabGroupForce);
            app.DistributedloadsTab.Title = 'Distributed loads';
            app.GridLayout26_2 = uigridlayout(app.DistributedloadsTab);
            app.Button_DistributedLoadsAdd = uibutton(app.GridLayout26_2, 'push');
            app.Button_DistributedLoadsAdd.ButtonPushedFcn = createCallbackFcn(app, @Button_DistributedLoadsAddPushed, true);
            app.Button_DistributedLoadsAdd.Text = 'Add';
            app.Panel_14 = uipanel(app.GridLayout26_2);
            app.GridLayout29 = uigridlayout(app.Panel_14);
            app.PitchSpinnerLabel = uilabel(app.GridLayout29);
            app.PitchSpinnerLabel.Text = 'Pitch';
            app.Spinner_DistributedLoadsPitch = uispinner(app.GridLayout29);
            app.Spinner_DistributedLoadsPitch.ValueDisplayFormat = '%11.4g ';
            app.StartValueSpinnerLabel = uilabel(app.GridLayout29);
            app.StartValueSpinnerLabel.Text = 'Start Value';
            app.Spinner_DistributedLoadsStartValue = uispinner(app.GridLayout29);
            app.Spinner_DistributedLoadsStartValue.ValueDisplayFormat = '%11.4g N/m';
            app.EndPositionSpinnerLabel = uilabel(app.GridLayout29);
            app.EndPositionSpinnerLabel.Text = 'End Position';
            app.Spinner_DistributedLoadsEndPosition = uispinner(app.GridLayout29);
            app.Spinner_DistributedLoadsEndPosition.ValueDisplayFormat = '%11.4g m';
            app.StartPositionSpinnerLabel = uilabel(app.GridLayout29);
            app.StartPositionSpinnerLabel.Text = 'Start Position';
            app.Spinner_DistributedLoadsStartPosition = uispinner(app.GridLayout29);
            app.Spinner_DistributedLoadsStartPosition.ValueDisplayFormat = '%11.4g m';
            app.Panel_15 = uipanel(app.GridLayout26_2);
            app.GridLayout30 = uigridlayout(app.Panel_15);
            app.Button_DistributedLoadsConstant = uibutton(app.GridLayout30, 'state');
            app.Button_DistributedLoadsConstant.ValueChangedFcn = createCallbackFcn(app, @Button_DistributedLoadsConstantValueChanged, true);
            app.Button_DistributedLoadsConstant.Icon = 'C:\Users\ichfr\OneDrive - FH JOANNEUM\Dokumente\Work\Technisches Programmieren Projekt\MATLAB\V4\constant_load.png';
            app.Button_DistributedLoadsConstant.Text = '';
            app.Button_DistributedLoadsLinear = uibutton(app.GridLayout30, 'state');
            app.Button_DistributedLoadsLinear.ValueChangedFcn = createCallbackFcn(app, @Button_DistributedLoadsLinearValueChanged, true);
            app.Button_DistributedLoadsLinear.Icon = 'C:\Users\ichfr\OneDrive - FH JOANNEUM\Dokumente\Work\Technisches Programmieren Projekt\MATLAB\V4\linear_load.png';
            app.Button_DistributedLoadsLinear.Text = '';
            app.Button_DistributedLoadsQuadratic = uibutton(app.GridLayout30, 'state');
            app.Button_DistributedLoadsQuadratic.ValueChangedFcn = createCallbackFcn(app, @Button_DistributedLoadsQuadraticValueChanged, true);
            app.Button_DistributedLoadsQuadratic.Icon = 'C:\Users\ichfr\OneDrive - FH JOANNEUM\Dokumente\Work\Technisches Programmieren Projekt\MATLAB\V4\quadratic_load.png';
            app.Button_DistributedLoadsQuadratic.Text = '';
            app.BarSetupPanel = uipanel(app.GridLayout5);
            app.BarSetupPanel.Title = 'Bar Setup';
            app.GridLayout6 = uigridlayout(app.BarSetupPanel);
            app.TabGroup3 = uitabgroup(app.GridLayout6);
            app.BarTab = uitab(app.TabGroup3);
            app.BarTab.Title = 'Bar';
            app.GridLayout18 = uigridlayout(app.BarTab);
            app.Panel_6 = uipanel(app.GridLayout18);
            app.GridLayout19 = uigridlayout(app.Panel_6);
            app.LengthSpinnerLabel = uilabel(app.GridLayout19);
            app.LengthSpinnerLabel.Text = 'Length';
            app.Spinner_BarLength = uispinner(app.GridLayout19);
            app.Spinner_BarLength.ValueDisplayFormat = '%11.4g m';
            app.Spinner_BarLength.ValueChangedFcn = createCallbackFcn(app, @Spinner_BarLengthValueChanged, true);
            app.Spinner_BarLength.Tag = 'Bar_Length';
            app.MaterialDropDownLabel = uilabel(app.GridLayout19);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.GridLayout19);
            app.MaterialDropDown.Items = {'Steel', 'Aluminium', 'Carbon', 'Oak'};
            app.MaterialDropDown.Value = 'Steel';
            app.ProfileDropDownLabel = uilabel(app.GridLayout19);
            app.ProfileDropDownLabel.Text = 'Profile';
            app.ProfileDropDown = uidropdown(app.GridLayout19);
            app.Button_BarAdd = uibutton(app.GridLayout18, 'push');
            app.Button_BarAdd.ButtonPushedFcn = createCallbackFcn(app, @Button_BarAddPushed, true);
            app.Button_BarAdd.Text = 'Add';
            app.BarprofileTab = uitab(app.TabGroup3);
            app.BarprofileTab.Title = 'Bar profile';
            app.Panel_2 = uipanel(app.GridLayout11);
            app.GridLayout13 = uigridlayout(app.Panel_2);
            app.Panel = uipanel(app.GridLayout13);
            app.GridLayout10 = uigridlayout(app.Panel);
            app.EditField_StaticDefinition1 = uieditfield(app.GridLayout10, 'numeric');
            app.EditField_StaticDefinition2 = uieditfield(app.GridLayout10, 'numeric');
            app.ForceNNmEditFieldLabel = uilabel(app.GridLayout10);
            app.ForceNNmEditFieldLabel.Text = '[Force] N N/m';
            app.ForceNNmEditField = uieditfield(app.GridLayout10, 'numeric');
            app.Label = uilabel(app.GridLayout10);
            app.Label.Text = '=';
            app.PositionmEditFieldLabel = uilabel(app.GridLayout10);
            app.PositionmEditFieldLabel.Text = '[Position]m';
            app.PositionmEditField = uieditfield(app.GridLayout10, 'numeric');
            app.AngledegEditFieldLabel = uilabel(app.GridLayout10);
            app.AngledegEditFieldLabel.Text = '[Angle] deg';
            app.AngledegEditField = uieditfield(app.GridLayout10, 'numeric');
            app.Lamp_StaticDefinition = uilamp(app.GridLayout10);
            app.DeleteViewButton = uibutton(app.GridLayout10, 'state');
            app.DeleteViewButton.Tooltip = {''};
            app.DeleteViewButton.Text = 'Delete/View';
            app.Panel_5 = uipanel(app.GridLayout13);
            app.GridLayout31 = uigridlayout(app.Panel_5);
            app.UIAxes_Setup = uiaxes(app.GridLayout31);
            title(app.UIAxes_Setup, 'Setup')
            xlabel(app.UIAxes_Setup, 'X')
            ylabel(app.UIAxes_Setup, 'Z')
            app.UIAxes_Setup.YDir = 'reverse';
            app.Panel_9 = uipanel(app.GridLayout31);
            app.Panel_16 = uipanel(app.Panel_9);
            app.Tab_Results = uitab(app.TabGroup_MainGui);
            app.Tab_Results.Title = 'Results';
            app.GridLayout14 = uigridlayout(app.Tab_Results);
            app.Panel_3 = uipanel(app.GridLayout14);
            app.GridLayout15 = uigridlayout(app.Panel_3);
            app.UIAxes_ResultsNormalForce = uiaxes(app.GridLayout15);
            title(app.UIAxes_ResultsNormalForce, 'Normal-Force')
            xlabel(app.UIAxes_ResultsNormalForce, 'X')
            ylabel(app.UIAxes_ResultsNormalForce, 'Z')
            app.UIAxes_ResultsShearForce = uiaxes(app.GridLayout15);
            title(app.UIAxes_ResultsShearForce, 'Shear-Force')
            xlabel(app.UIAxes_ResultsShearForce, 'X')
            ylabel(app.UIAxes_ResultsShearForce, 'Z')
            app.UIAxes_ResultsMoment = uiaxes(app.GridLayout15);
            title(app.UIAxes_ResultsMoment, 'Moment')
            xlabel(app.UIAxes_ResultsMoment, 'X')
            ylabel(app.UIAxes_ResultsMoment, 'Z')
            app.Panel_4 = uipanel(app.GridLayout14);
            app.GridLayout16 = uigridlayout(app.Panel_4);
            app.CalculateForcesataspecificpointPanel = uipanel(app.GridLayout16);
            app.CalculateForcesataspecificpointPanel.Title = 'Calculate Forces at a specific point';
            app.GridLayout17 = uigridlayout(app.CalculateForcesataspecificpointPanel);
            app.PositionEditFieldLabel = uilabel(app.GridLayout17);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.GridLayout17, 'numeric');
            app.PositionEditField.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldValueChanged, true);
            app.XForceEditFieldLabel = uilabel(app.GridLayout17);
            app.XForceEditFieldLabel.Text = 'X-Force';
            app.XForceEditField = uieditfield(app.GridLayout17, 'numeric');
            app.ZForceEditFieldLabel = uilabel(app.GridLayout17);
            app.ZForceEditFieldLabel.Text = 'Z-Force';
            app.ZForceEditField = uieditfield(app.GridLayout17, 'numeric');
            app.MomentEditFieldLabel = uilabel(app.GridLayout17);
            app.MomentEditFieldLabel.Text = 'Moment';
            app.MomentEditField = uieditfield(app.GridLayout17, 'numeric');
            app.BearingReactionsPanel = uipanel(app.GridLayout16);
            app.BearingReactionsPanel.Title = 'Bearing Reactions';
            app.GridLayout33 = uigridlayout(app.BearingReactionsPanel);
            app.UITable_Results = uitable(app.GridLayout33);
            app.UITable_Results.ColumnName = {'Index'; 'Normal-Force'; 'Shear-Force'; 'Moment'};
            app.UITable_Results.RowName = {};
            % Show the figure after all components are created
            app.CIFRIBUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CIFRIB_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CIFRIBUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CIFRIBUIFigure)
        end
    end
end