classdef CIFRIB_v1 < matlab.apps.AppBase
    %CIFRIB_V1 Program for the calculation of internal forces and reactions
    %inside of bars

    % Properties that correspond to app components
    properties (Access = public)
        CIFRIBUIFigure                 matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        Menu_New                       matlab.ui.container.Menu
        ExamplesMenu                   matlab.ui.container.Menu
        Example1Menu                   matlab.ui.container.Menu
        Example2Menu                   matlab.ui.container.Menu
        Example3Menu                   matlab.ui.container.Menu
        Example4Menu                   matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveAsMenu                     matlab.ui.container.Menu
        ExportMenu                     matlab.ui.container.Menu
        PDFMenu                        matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        Menu_Undo                      matlab.ui.container.Menu
        Menu_Redo                      matlab.ui.container.Menu
        PreferencesMenu                matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        UserManualMenu                 matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        AboutMenu_2                    matlab.ui.container.Menu
        WebsiteMenu                    matlab.ui.container.Menu
        GridLayout12                   matlab.ui.container.GridLayout
        TabGroup_MainGui               matlab.ui.container.TabGroup
        Tab_Setup                      matlab.ui.container.Tab
        GridLayout11                   matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        GridLayout13                   matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        Lamp_StaticDefinition          matlab.ui.control.Lamp
        EditField_StaticDefinition2    matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        EditField_StaticDefinition1    matlab.ui.control.NumericEditField
        DeleteViewButton               matlab.ui.control.StateButton
        ForceNNmEditField              matlab.ui.control.NumericEditField
        ForceNNmEditFieldLabel         matlab.ui.control.Label
        PositionmEditField             matlab.ui.control.NumericEditField
        PositionmLabel                 matlab.ui.control.Label
        AngledegEditField              matlab.ui.control.NumericEditField
        AngledegEditFieldLabel         matlab.ui.control.Label
        Panel_5                        matlab.ui.container.Panel
        GridLayout31                   matlab.ui.container.GridLayout
        UIAxes_Setup                   matlab.ui.control.UIAxes
        LeftPanel                      matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        Button_SetupCalculate          matlab.ui.control.Button
        Panel_Loads                    matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        TabGroupForce                  matlab.ui.container.TabGroup
        PointloadsTab                  matlab.ui.container.Tab
        GridLayout26                   matlab.ui.container.GridLayout
        Button_PointloadsAdd           matlab.ui.control.Button
        Panel_12                       matlab.ui.container.Panel
        GridLayout27                   matlab.ui.container.GridLayout
        Spinner_PointLoadsAngle        matlab.ui.control.Spinner
        AngleSpinnerLabel              matlab.ui.control.Label
        Spinner_PointLoadsForce        matlab.ui.control.Spinner
        ForceSpinnerLabel              matlab.ui.control.Label
        Spinner_PointLoadsPosition     matlab.ui.control.Spinner
        PositionSpinner_3Label         matlab.ui.control.Label
        Panel_13                       matlab.ui.container.Panel
        GridLayout28                   matlab.ui.container.GridLayout
        Button_PointloadsMoment        matlab.ui.control.StateButton
        Button_PointLoadsForce         matlab.ui.control.StateButton
        DistributedloadsTab            matlab.ui.container.Tab
        GridLayout26_2                 matlab.ui.container.GridLayout
        Button_DistributedLoadsAdd     matlab.ui.control.Button
        Panel_14                       matlab.ui.container.Panel
        GridLayout29                   matlab.ui.container.GridLayout
        Spinner_DistributedLoadsPitch  matlab.ui.control.Spinner
        PitchSpinnerLabel              matlab.ui.control.Label
        Spinner_DistributedLoadsStartValue  matlab.ui.control.Spinner
        StartValueSpinnerLabel         matlab.ui.control.Label
        Spinner_DistributedLoadsEndPosition  matlab.ui.control.Spinner
        EndPositionSpinnerLabel        matlab.ui.control.Label
        Spinner_DistributedLoadsStartPosition  matlab.ui.control.Spinner
        StartPositionSpinnerLabel      matlab.ui.control.Label
        Panel_15                       matlab.ui.container.Panel
        GridLayout30                   matlab.ui.container.GridLayout
        Button_DistributedLoadsQuadratic  matlab.ui.control.StateButton
        Button_DistributedLoadsLinear  matlab.ui.control.StateButton
        Button_DistributedLoadsConstant  matlab.ui.control.StateButton
        SupportsPanel                  matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        SupportsTab                    matlab.ui.container.Tab
        GridLayout20                   matlab.ui.container.GridLayout
        Button_SupportAdd              matlab.ui.control.Button
        Panel_8                        matlab.ui.container.Panel
        GridLayout21                   matlab.ui.container.GridLayout
        Spinner_SupportPosition        matlab.ui.control.Spinner
        PositionSpinnerLabel           matlab.ui.control.Label
        Panel_7                        matlab.ui.container.Panel
        GridLayout22                   matlab.ui.container.GridLayout
        Button_SupportClampedSupport   matlab.ui.control.StateButton
        Button_SupportFixedBearing     matlab.ui.control.StateButton
        Button_SupportLooseBearing     matlab.ui.control.StateButton
        HingesTab                      matlab.ui.container.Tab
        GridLayout23                   matlab.ui.container.GridLayout
        Button_HingeAdd                matlab.ui.control.Button
        Panel_11                       matlab.ui.container.Panel
        GridLayout25                   matlab.ui.container.GridLayout
        Spinner_HingePosition          matlab.ui.control.Spinner
        PositionSpinner_2Label         matlab.ui.control.Label
        Panel_10                       matlab.ui.container.Panel
        GridLayout32                   matlab.ui.container.GridLayout
        Button_HingeNormalforce        matlab.ui.control.StateButton
        Button_HingeShearforce         matlab.ui.control.StateButton
        Button_HingeMoment             matlab.ui.control.StateButton
        BarSetupPanel                  matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        TabGroup3                      matlab.ui.container.TabGroup
        BarTab                         matlab.ui.container.Tab
        GridLayout18                   matlab.ui.container.GridLayout
        Panel_17                       matlab.ui.container.Panel
        GridLayout34                   matlab.ui.container.GridLayout
        Button_BarAdd                  matlab.ui.control.Button
        Panel_6                        matlab.ui.container.Panel
        GridLayout19                   matlab.ui.container.GridLayout
        SteelDropDown                  matlab.ui.control.DropDown
        SteelDropDownLabel             matlab.ui.control.Label
        MaterialDropDown               matlab.ui.control.DropDown
        MaterialDropDownLabel          matlab.ui.control.Label
        Spinner_BarLength              matlab.ui.control.Spinner
        LengthSpinnerLabel             matlab.ui.control.Label
        BarprofileTab                  matlab.ui.container.Tab
        GridLayout36                   matlab.ui.container.GridLayout
        Panel_19                       matlab.ui.container.Panel
        GridLayout38                   matlab.ui.container.GridLayout
        Spinner_BarprofileA            matlab.ui.control.Spinner
        Spinner_BarprofileB            matlab.ui.control.Spinner
        Panel_18                       matlab.ui.container.Panel
        GridLayout37                   matlab.ui.container.GridLayout
        Button_BarprofileH             matlab.ui.control.StateButton
        Button_BarprofileRound         matlab.ui.control.StateButton
        Button_BarprofileSquarehollow  matlab.ui.control.StateButton
        Button_BarprofileRektangle     matlab.ui.control.StateButton
        Tab_Results                    matlab.ui.container.Tab
        GridLayout14                   matlab.ui.container.GridLayout
        Panel_4                        matlab.ui.container.Panel
        GridLayout16                   matlab.ui.container.GridLayout
        Panel_20                       matlab.ui.container.Panel
        GridLayout39                   matlab.ui.container.GridLayout
        AdditionalDetailsPanel         matlab.ui.container.Panel
        GridLayout35                   matlab.ui.container.GridLayout
        SafetyyieldingEditField        matlab.ui.control.NumericEditField
        SafetyyieldingEditFieldLabel   matlab.ui.control.Label
        SafetyfractureEditField        matlab.ui.control.NumericEditField
        SafetyfractureEditFieldLabel   matlab.ui.control.Label
        SumofsquarederrorsPanel        matlab.ui.container.Panel
        GridLayout40                   matlab.ui.container.GridLayout
        EditField_ResultsSumofsquarederrors  matlab.ui.control.NumericEditField
        CalculateForcesataspecificpointPanel  matlab.ui.container.Panel
        GridLayout17                   matlab.ui.container.GridLayout
        MomentEditField                matlab.ui.control.NumericEditField
        MomentEditFieldLabel           matlab.ui.control.Label
        ZForceEditField                matlab.ui.control.NumericEditField
        ZForceEditFieldLabel           matlab.ui.control.Label
        XForceEditField                matlab.ui.control.NumericEditField
        XForceEditFieldLabel           matlab.ui.control.Label
        PositionEditField              matlab.ui.control.NumericEditField
        PositionEditFieldLabel         matlab.ui.control.Label
        BearingReactionsPanel          matlab.ui.container.Panel
        GridLayout33                   matlab.ui.container.GridLayout
        UITable_Results                matlab.ui.control.Table
        Panel_3                        matlab.ui.container.Panel
        GridLayout15                   matlab.ui.container.GridLayout
        UIAxes_ResultsMoment           matlab.ui.control.UIAxes
        UIAxes_ResultsShearForce       matlab.ui.control.UIAxes
        UIAxes_ResultsNormalForce      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        HelpApp
        AboutApp
        PreferencesApp
        InitialSetupApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global programVersion deviceId sessionId
            programVersion = '1.2';

            structureinitiate; % initiate the struct in which all values are written
            
            % Generate a persistent device ID on first install
            if ispref('cifrib', 'device_id')
                deviceId = getpref('cifrib', 'device_id'); % Retrieve stored ID
            else
                try
                    deviceId = char(java.util.UUID.randomUUID()); % Generate unique ID
                catch
                    deviceId = randi(999999999); % fallback if java isnt available
                end
                setpref('cifrib', 'device_id', deviceId); % Save it persistently
            end

            try
                sessionId = char(java.util.UUID.randomUUID());
            catch
                sessionId = randi(999999999);
            end
            
            
            if ~ispref('cifrib','language')
                addpref('cifrib','language','english')
            end
            if ~ispref('cifrib','theme')
                addpref('cifrib','theme','light')
            end
            if ~ispref('cifrib', 'hideInitialSetup')
                addpref('cifrib', 'hideInitialSetup', false)
                app.InitialSetupApp = InitialSetup(app);
            elseif ~getpref('cifrib', 'hideInitialSetup')
                app.InitialSetupApp = InitialSetup(app);
            end

            applyLanguage(app)
            applyTheme(app)
            loadPreferences(app)
            % Check for the latest version during app start
            try
                latestVersion = sendAnalytics(sessionId, deviceId, programVersion, 'app_start');
                
                % Compare versions and notify user
                if str2double(latestVersion) > str2double(programVersion)


                    % Ui dialog
                    msg = "A new version is available";
                    title = "Update available";
                    selection = uiconfirm(app.CIFRIBUIFigure,msg,title, ...
                        "Options",["Download","Not now"], ...
                        "DefaultOption",1,'Icon', 'info');
                    switch selection
                        case 'Download'
                            web('www.schnittgroessen.com')
                        case 'Not now'
                            %something
                    end


                end
            catch
                warning('Failed to check for updates.');
            end
        end

        % Value changed function: Spinner_BarLength
        function Spinner_BarLengthValueChanged(app, event)
            % global lbar main;
            % lbar = app.Spinner_BarLength.Value; % set length of bar when value is changed in edit field
            % main.BarLgh = lbar;
        end

        % Button pushed function: Button_BarAdd
        function Button_BarAddPushed(app, event)
            try
                % Check if the numeric field has a value
            global lbar main;
            if isempty(app.Spinner_BarLength.Value) || isnan(app.Spinner_BarLength.Value) || (app.Spinner_BarLength.Value <= 0)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'Please enter a value for Bar Length > 0', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end
            if  ~isempty(main.BarLgh) && any(main.BarLgh ~= 0)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'If you want to change the bar length, please start a new project', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end
            lbar = app.Spinner_BarLength.Value; % set length of bar to value in edit field
            main.BarLgh = app.Spinner_BarLength.Value; % set length of bar to value in edit field
            PlotAll(app);
            % Update Static definition & Lamp
            app.EditField_StaticDefinition1.Value = 0;
            app.EditField_StaticDefinition2.Value = 3;
            app.Lamp_StaticDefinition.Color = [1 0 0];
            %staticallyindeterminateValueChanged(app);
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Button pushed function: Button_SupportAdd
        function Button_SupportAddPushed(app, event)
            try
                global main
            if isempty(main.BarLgh) || (main.BarLgh == 0)
               uialert(app.CIFRIBUIFigure, ...
                    'Please add a bar first', ...
                    'Input Error', ...
                    'Icon', 'error');
              return
            end
            if ~any([app.Button_SupportClampedSupport.Value, app.Button_SupportFixedBearing.Value, app.Button_SupportLooseBearing.Value])
               uialert(app.CIFRIBUIFigure, ...
                    'Please select a Support type', ...
                    'Input Error', ...
                    'Icon', 'error');
              return
            end
            if isempty(any(app.Spinner_SupportPosition.Value)) || (any(app.Spinner_SupportPosition.Value > main.BarLgh))
               uialert(app.CIFRIBUIFigure, ...
                    'Please enter a position inside the bar', ...
                    'Input Error', ...
                    'Icon', 'error');
              return
            end
            if 0 %(length(main.Bearing.Position) >= 2)        % disabled
               uialert(app.CIFRIBUIFigure, ...
                    'Max. number of supports reached', ...
                    'Support Limit', ...
                    'Icon', 'error');
              return
            end
            if (app.Button_SupportClampedSupport.Value == true) &&  ~(app.Spinner_SupportPosition.Value == 0)
               uialert(app.CIFRIBUIFigure, ...
                    'Clamped support must be on the left end of the bar', ...
                    'Support Placement', ...
                    'Icon', 'error');
              return
            end
            

        global main

        % Loose_Bearing
        if app.Button_SupportLooseBearing.Value == true

            % Handover to main.Bearing
            AddBearing(app.Spinner_SupportPosition.Value, 0, 1, 0);

            % Reset Button_SupportLooseBearing
            app.Button_SupportLooseBearing.Value = false;


        % Fixed_Bearing
        elseif app.Button_SupportFixedBearing.Value == true
            
            % Handover to main.Bearing
            AddBearing(app.Spinner_SupportPosition.Value, 1, 1, 0);

            % Reset Button_SupportFixedBearing
            app.Button_SupportFixedBearing.Value = false;
   

        % Clamped_Support
        elseif (app.Button_SupportClampedSupport.Value == true) %&&  (app.Spinner_SupportPosition.Value == 0) || (app.Spinner_SupportPosition.Value == main.BarLgh(1))
                
            % Handover to main.Bearing
            AddBearing(app.Spinner_SupportPosition.Value, 1, 1, 1);

            % Reset Button_SupportClampedSupport
            app.Button_SupportClampedSupport.Value = false;
        end

            % Static Definition
            app.EditField_StaticDefinition1.Value = StaticDefinitionBearing;
            % staticallyindeterminateValueChanged(app);

            % Update Static definition Lamp
            if app.EditField_StaticDefinition1.Value == app.EditField_StaticDefinition2.Value
                app.Lamp_StaticDefinition.Color = [0 1 0];
            else
                app.Lamp_StaticDefinition.Color = [1 0 0];
            end

            % Plot
            PlotAll(app);
            %PlotBearing(app);

            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Button pushed function: Button_PointloadsAdd
        function Button_PointloadsAddPushed(app, event)
            try
                global main
            %Error Messages 
            if isempty(main.BarLgh) || (main.BarLgh == 0)
               uialert(app.CIFRIBUIFigure, ...
                    'Please add a bar first', ...
                    'Input Error', ...
                    'Icon', 'error');
              return
            end
            if ~any([app.Button_PointLoadsForce.Value, app.Button_PointloadsMoment.Value])
               uialert(app.CIFRIBUIFigure, ...
                    'Please select a load type', ...
                    'Input Error', ...
                    'Icon', 'error');
              return
            end
            if isempty(app.Spinner_PointLoadsForce.Value) || isnan(app.Spinner_PointLoadsForce.Value) || (app.Spinner_PointLoadsForce.Value == 0)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'Please enter a value for Force.', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end
            if isempty(app.Spinner_PointLoadsPosition.Value) || isnan(app.Spinner_PointLoadsPosition.Value) || (app.Spinner_PointLoadsPosition.Value < 0) || (app.Spinner_PointLoadsPosition.Value > main.BarLgh) % app.Spinner_BarLength.Value)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'The Position is outside the bar. Please enter a valid value.', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end


            % Add Force
        if app.Button_PointLoadsForce.Value == true

            % Check if input is resonabile
            if any(app.Spinner_PointLoadsForce.Value == 0) || isempty(app.Spinner_PointLoadsForce.Value) || any(app.Spinner_PointLoadsPosition.Value > main.BarLgh) || any(app.Spinner_PointLoadsPosition.Value < 0)
                return
            else
                % Plot Force(function) aber erst nach add force aufruf
                global main;
                main.DelInf = [];
                
                %Aufrufen der Funktion
                AddForce(app.Spinner_PointLoadsForce.Value, app.Spinner_PointLoadsPosition.Value, app.Spinner_PointLoadsAngle.Value);

                PlotAll(app)
            end
        end

        % Add Tourque

        
        if app.Button_PointloadsMoment.Value == true

            %Moment
            % Check if input is resonabile
            if any(app.Spinner_PointLoadsForce.Value == 0) || isempty(app.Spinner_PointLoadsForce.Value)
                return
            else
                %Übergabe in main-Structure
                AddTorque(app.Spinner_PointLoadsForce.Value, app.Spinner_PointLoadsPosition.Value);

                PlotAll(app)
            end

        end
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Button pushed function: Button_DistributedLoadsAdd
        function Button_DistributedLoadsAddPushed(app, event)
            try
                global main

            %Error Messages
            if isempty(main.BarLgh) || (main.BarLgh == 0)
               uialert(app.CIFRIBUIFigure, ...
                    'Please add a bar first', ...
                    'Input Error', ...
                    'Icon', 'error');
              return
            end
            if ~any([app.Button_DistributedLoadsConstant.Value, app.Button_DistributedLoadsLinear.Value, app.Button_DistributedLoadsQuadratic.Value])
               uialert(app.CIFRIBUIFigure, ...
                    'Please select a load type', ...
                    'Input Error', ...
                    'Icon', 'error');
              return
            end
            if isempty(app.Spinner_DistributedLoadsStartPosition.Value) || isnan(app.Spinner_DistributedLoadsStartPosition.Value) || (app.Spinner_DistributedLoadsStartPosition.Value >= main.BarLgh) || (app.Spinner_DistributedLoadsStartPosition.Value >= app.Spinner_DistributedLoadsEndPosition.Value)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'Please enter a valid Start Position.', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end
            if isempty(app.Spinner_DistributedLoadsEndPosition.Value) || isnan(app.Spinner_DistributedLoadsEndPosition.Value) || (app.Spinner_DistributedLoadsEndPosition.Value > main.BarLgh) || (app.Spinner_DistributedLoadsEndPosition.Value < app.Spinner_DistributedLoadsStartPosition.Value)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'Please enter a valid End Value.', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end
            if isempty(app.Spinner_DistributedLoadsStartValue.Value) || isnan(app.Spinner_DistributedLoadsStartValue.Value)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'Please enter a Start Value.', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end
            if isempty(app.Spinner_DistributedLoadsPitch.Value) || isnan(app.Spinner_DistributedLoadsPitch.Value)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'Please enter a Pitch.', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end


            % Check if input is reasonabile
            if isempty(app.Spinner_DistributedLoadsStartValue.Value) || isempty(app.Spinner_DistributedLoadsStartPosition.Value) || isempty(app.Spinner_DistributedLoadsEndPosition.Value) || (app.Spinner_DistributedLoadsStartPosition.Value > main.BarLgh) || (app.Spinner_DistributedLoadsEndPosition.Value > main.BarLgh) || (app.Spinner_DistributedLoadsStartPosition.Value < 0) || (app.Spinner_DistributedLoadsEndPosition.Value < 0)
            return
            end

            global main;
            main.DelInf = [];
          if app.Button_DistributedLoadsConstant.Value == true
              constshape1exponent = 0;
          elseif app.Button_DistributedLoadsLinear.Value == true
              constshape1exponent = 1;
          elseif app.Button_DistributedLoadsQuadratic.Value == true
              constshape1exponent = 2;
          end
          if app.Button_DistributedLoadsConstant.Value == true
                constshape1exponent = 0;
                disp('Constant load selected');
            elseif app.Button_DistributedLoadsLinear.Value == true
                constshape1exponent = 1;
                disp('Linear load selected');
            elseif app.Button_DistributedLoadsQuadratic.Value == true
                constshape1exponent = 2;
                disp('Quadratic load selected');
            else
                disp('No load type selected');
            end

        AddDistl(app.Spinner_DistributedLoadsStartPosition.Value,app.Spinner_DistributedLoadsEndPosition.Value,app.Spinner_DistributedLoadsStartValue.Value,1,constshape1exponent,app.Spinner_DistributedLoadsPitch.Value);
        PlotAll(app);
       
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Button pushed function: Button_SetupCalculate
        function Button_SetupCalculatePushed(app, event)
           try
                global main results SSE;

                % Error Messages
                if isempty(main.BarLgh) || (main.BarLgh == 0)
                   uialert(app.CIFRIBUIFigure, ...
                        'Please add a bar first', ...
                        'Input Error', ...
                        'Icon', 'error');
                  return
                end
                if isempty(main.Bearing.Position)
                   uialert(app.CIFRIBUIFigure, ...
                        'Please add Supports first', ...
                        'Input Error', ...
                        'Icon', 'error');
                  return
                end
                if all([isempty(main.Force.Position) isempty(main.Torque.Position) isempty(main.Distl.StartPos)])
                   uialert(app.CIFRIBUIFigure, ...
                        'Please add a Load first', ...
                        'Input Error', ...
                        'Icon', 'error');
                  return
                end
                if app.EditField_StaticDefinition1.Value ~= app.EditField_StaticDefinition2.Value
                        uialert(app.CIFRIBUIFigure, ...
                        'Please first establish a static determination', ...
                        'Input Error', ...
                        'Icon', 'error');
                  return
                end

                % calculate everything
                app.Button_SetupCalculate.Text = 'Calculating';
                app.Button_SetupCalculate.Icon = 'icons/loading.gif';
                app.Button_SetupCalculate.Enable = "off";
                drawnow;
                
                try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'calculation', main);
                catch
                end
    
                %app.TabGroup_MainGui.SelectedTab = app.Tab_Results;
                %fplot(app.UIAxes_ResultsNormalForce, results.Fx, [0,app.Spinner_BarLength.Value]);
                %fplot(app.UIAxes_ResultsMoment, results.Mb, [0,app.Spinner_BarLength.Value]);
                %fplot(app.UIAxes_ResultsShearForce, results.Fz, [0,app.Spinner_BarLength.Value]);
                calcformulasanalytico3(app.Spinner_BarLength.Value);
                app.TabGroup_MainGui.SelectedTab = app.Tab_Results;
                fplot(app.UIAxes_ResultsNormalForce, results.Fx, [0, app.Spinner_BarLength.Value]);
                fplot(app.UIAxes_ResultsMoment, results.Mb, [0, app.Spinner_BarLength.Value]);
                fplot(app.UIAxes_ResultsShearForce, results.Fz, [0, app.Spinner_BarLength.Value]);


                app.Button_SetupCalculate.Enable = "on";
                app.Button_SetupCalculate.Text = 'Calculate!';
                app.Button_SetupCalculate.Icon = '';
                drawnow;
                app.UITable_Results.Data = results.BearingForces;

                app.EditField_ResultsSumofsquarederrors.Value = SSE;


                %Calculation of Safety
                if all(app.MaterialDropDown.Value ~= "Standard") && all(app.Spinner_BarprofileA.Value ~= 0) && all(app.Spinner_BarprofileB.Value ~= 0) && ~all(isempty(app.Spinner_BarprofileA.Value)) && ~all(isempty(app.Spinner_BarprofileB.Value))
    
                    xValues = linspace(0, app.Spinner_BarLength.Value, app.Spinner_BarLength.Value.^2);
                    yValues = results.Mb(xValues);
                    Mb = max(yValues) * 1000;
    
                    if Mb ~= 0
    
                        if app.MaterialDropDown.Value == "E335"
                            R_m = 590;
                            R_p02 = 335;
                        end
    
                        if app.MaterialDropDown.Value == "S275JR"
                            R_m = 430;
                            R_p02 = 275;
                        end
    
                        if app.MaterialDropDown.Value == "AW-6061 T4"
                            R_m = 205;
                            R_p02 = 110;
                        end
    
                        if app.Button_BarprofileRektangle.Value == true
                        b = app.Spinner_BarprofileB.Value;
                        h = app.Spinner_BarprofileA.Value;
                        I = b * h ^ 3 / 12;
                        z_max = h / 2;
    
                        S = Mb * z_max / I;
                        SF = R_p02 / S;
                        SB = R_m / S;
        
                        SF = round(SF, 4);
                        SB = round(SB, 4);
                        app.SafetyfractureEditField.Value = SB;
                        app.SafetyyieldingEditField.Value = SF;
                        end
    
                        if app.Button_BarprofileSquarehollow.Value == true
                        b = app.Spinner_BarprofileA.Value - 2 * app.Spinner_BarprofileB.Value;
                        B = app.Spinner_BarprofileA.Value;
                        I = (B ^ 4 - b ^ 4) / 12;
                        z_max = B / 2;
    
                        S = Mb * z_max / I;
                        SF = R_p02 / S;
                        SB = R_m / S;
        
                        SF = round(SF, 4);
                        SB = round(SB, 4);
                        app.SafetyfractureEditField.Value = SB;
                        app.SafetyyieldingEditField.Value = SF;
                        end
    
                        if app.Button_BarprofileRound.Value == true
                        d = app.Spinner_BarprofileA.Value - 2 * app.Spinner_BarprofileB.Value;
                        D = app.Spinner_BarprofileA.Value;
                        I = (D ^ 4 - d ^ 4) * pi / 64;
                        z_max = D / 2;
    
                        S = Mb * z_max / I;
                        SF = R_p02 / S;
                        SB = R_m / S;
        
                        SF = round(SF, 4);
                        SB = round(SB, 4);
                        app.SafetyfractureEditField.Value = SB;
                        app.SafetyyieldingEditField.Value = SF;
                        end
    
                        if app.Button_BarprofileH.Value == true
                        H = app.Spinner_BarprofileA.Value;
                        h = app.Spinner_BarprofileA.Value - 2 * app.Spinner_BarprofileB.Value;
                        B = app.Spinner_BarprofileA.Value;
                        b = app.Spinner_BarprofileA.Value - app.Spinner_BarprofileB.Value;
                        I = (B * H ^ 3 - b * h ^ 3) / 12;
                        z_max = H / 2;
    
                        S = Mb * z_max / I;
                        SF = R_p02 / S;
                        SB = R_m / S;
        
                        SF = round(SF, 4);
                        SB = round(SB, 4);
                        app.SafetyfractureEditField.Value = SB;
                        app.SafetyyieldingEditField.Value = SF;
                        end 
                    end
                end

            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
                 app.Button_SetupCalculate.Enable = "on";
                 app.Button_SetupCalculate.Text = 'Calculate!';
                 app.Button_SetupCalculate.Icon = '';
                 drawnow;
            end
        end

        % Menu selected function: Menu_New
        function Menu_NewSelected(app, event)
            try
                cla(app.UIAxes_Setup)
            structureinitiate();
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Button pushed function: Button_HingeAdd
        function Button_HingeAddPushed(app, event)
            try
                global main
            % Error Messages
            % if isempty(main.BarLgh) || (main.BarLgh == 0)
            %    uialert(app.CIFRIBUIFigure, ...
            %         'Please add a bar first', ...
            %         'Input Error', ...
            %         'Icon', 'error');
            %   return
            % end
            if ~any([app.Button_HingeMoment.Value, app.Button_HingeNormalforce.Value, app.Button_HingeShearforce.Value])
               uialert(app.CIFRIBUIFigure, ...
                    'Please select a hinge type', ...
                    'Input Error', ...
                    'Icon', 'error');
              return
            end
            if isempty(app.Spinner_HingePosition.Value) || isnan(app.Spinner_HingePosition.Value)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'Please enter a valid value.', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end
            if (app.Spinner_HingePosition.Value <= 0) || (app.Spinner_HingePosition.Value > main.BarLgh)
                % Display an error message as a pop-up
                uialert(app.CIFRIBUIFigure, ...
                    'The Position is outside the bar. Please enter a valid value.', ...
                    'Input Error', ...
                    'Icon', 'error');
                return; % Stop further execution
            end
            % if width(main.Joint) == 1
            %     uialert(app.CIFRIBUIFigure, ...
            %         'It is not jet possible to add more than one Hinge sorry...', ...
            %         'Input Error', ...
            %         'Icon', 'error');
            %     return; % Stop further execution
            % end

            % show inaccuracy warning
            % uialert(app.CIFRIBUIFigure, ...
            %      'Calculation with hinges is not accurate and can have an error of about 15 %', ...
            %      'Warning', ...
            %      'Icon', 'warning');

           % MomentHinge
            if app.Button_HingeMoment.Value == true

                % Handover to the structure
                AddJoint(app.Spinner_HingePosition.Value, 1, 1, 0)

                % Reset Button_HingeMoment
                app.Button_HingeMoment.Value = false;


            elseif app.Button_HingeNormalforce.Value == true

                % Handover to the structure
                AddJoint(app.Spinner_HingePosition.Value, 0, 1, 1)
    
                % Reset Button_HingeNormalforce
                app.Button_HingeNormalforce.Value = false;


            elseif app.Button_HingeShearforce.Value == true
      
                % Handover to the structure
                AddJoint(app.Spinner_HingePosition.Value, 1, 0, 1)
    
                % Reset Button_HingeShearforce
                app.Button_HingeShearforce.Value = false;

            end

                % Static Definition
                app.EditField_StaticDefinition2.Value = StaticDefinitionHinge;
                %staticallyindeterminateValueChanged(app);

                % Update Static definition Lamp
            if app.EditField_StaticDefinition1.Value == app.EditField_StaticDefinition2.Value
                app.Lamp_StaticDefinition.Color = [0 1 0];
            else
                app.Lamp_StaticDefinition.Color = [1 0 0];
            end

                % Plot 
                PlotHinge(app);

            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Menu selected function: Menu_Undo
        function Menu_UndoSelected(app, event)
            try
                Undo(app);
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end  
        end

        % Menu selected function: Menu_Redo
        function Menu_RedoSelected(app, event)
            try
                Redo(app);
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: PositionEditField
        function PositionEditFieldValueChanged(app, event)
            try
                global results;
          
                value = app.PositionEditField.Value;
                app.MomentEditField.Value = round(results.Mb(value),4);
                app.ZForceEditField.Value = results.Fz(value);
                app.XForceEditField.Value = results.Fx(value);
    
                if app.PositionEditField.Value < 0 || app.PositionEditField.Value >= app.Spinner_BarLength.Value
                    app.MomentEditField.Value = 0;
                    app.ZForceEditField.Value = 0;
                    app.XForceEditField.Value = 0;
                end
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Button_HingeMoment
        function Button_HingeMomentValueChanged(app, event)
            try
                %value = app.Button_HingeMoment.Value;
                app.Button_HingeNormalforce.Value = false;
                app.Button_HingeShearforce.Value = false;  
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Button_HingeShearforce
        function Button_HingeShearforceValueChanged(app, event)
            try
                %value = app.Button_HingeShearforce.Value;
                app.Button_HingeNormalforce.Value = false;
                app.Button_HingeMoment.Value = false;
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Button_HingeNormalforce
        function Button_HingeNormalforceValueChanged(app, event)
            try
                %value = app.Button_HingeNormalforce.Value;
                app.Button_HingeMoment.Value = false;
                app.Button_HingeShearforce.Value = false;
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Button_SupportLooseBearing
        function Button_SupportLooseBearingValueChanged(app, event)
            try
                %value = app.Button_SupportLooseBearing.Value;
                app.Button_SupportClampedSupport.Value = false;
                app.Button_SupportFixedBearing.Value = false;
                app.Spinner_SupportPosition.Enable = true;
                app.Spinner_SupportPosition.Tooltip = '';
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Button_SupportFixedBearing
        function Button_SupportFixedBearingValueChanged(app, event)
            try
                %value = app.Button_SupportFixedBearing.Value;
                app.Button_SupportClampedSupport.Value = false;
                app.Button_SupportLooseBearing.Value = false;
                app.Spinner_SupportPosition.Enable = true;
                app.Spinner_SupportPosition.Tooltip = '';
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Button_SupportClampedSupport
        function Button_SupportClampedSupportValueChanged(app, event)
            try
                %value = app.Button_SupportClampedSupport.Value;
                app.Button_SupportLooseBearing.Value = false;
                app.Button_SupportFixedBearing.Value = false;
                app.Spinner_SupportPosition.Value = 0;
                app.Spinner_SupportPosition.Enable = false;
                app.Spinner_SupportPosition.Tooltip = 'currently working only on the left end';
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Button_PointLoadsForce
        function Button_PointLoadsForceValueChanged(app, event)
            try
                %value = app.Button_PointLoadsForce.Value;
                app.Button_PointloadsMoment.Value = false;
                app.Spinner_PointLoadsForce.ValueDisplayFormat = '%11.4g N';
                app.Spinner_PointLoadsAngle.Enable = true;
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Button_PointloadsMoment
        function Button_PointloadsMomentValueChanged(app, event)
            try
                %value = app.Button_PointloadsMoment.Value;
                app.Button_PointLoadsForce.Value = false;
                app.Spinner_PointLoadsForce.ValueDisplayFormat = '%11.4g Nm';
                app.Spinner_PointLoadsAngle.Enable = false;
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Button_DistributedLoadsConstant
        function Button_DistributedLoadsConstantValueChanged(app, event)
            try
                %value = app.Button_DistributedLoadsConstant.Value;
                app.Button_DistributedLoadsLinear.Value = false;
                app.Button_DistributedLoadsQuadratic.Value = false;
                app.Spinner_DistributedLoadsPitch.Value = 0;
                if app.Button_DistributedLoadsConstant.Value == true 
                    app.Spinner_DistributedLoadsPitch.Enable = false;
                else
                    app.Spinner_DistributedLoadsPitch.Enable = true;
                end
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Button_DistributedLoadsLinear
        function Button_DistributedLoadsLinearValueChanged(app, event)
            try
                %value = app.Button_DistributedLoadsLinear.Value;
                app.Button_DistributedLoadsConstant.Value = false;
                app.Button_DistributedLoadsQuadratic.Value = false;
                app.Spinner_DistributedLoadsPitch.Enable = true;
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Button_DistributedLoadsQuadratic
        function Button_DistributedLoadsQuadraticValueChanged(app, event)
            try
                %value = app.Button_DistributedLoadsQuadratic.Value;
                app.Button_DistributedLoadsLinear.Value = false;
                app.Button_DistributedLoadsConstant.Value = false;
                app.Spinner_DistributedLoadsPitch.Enable = true;
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Menu selected function: UserManualMenu
        function HelpMenuScreen(app, event)
            %run Helpv2.mlapp;
            try
                global sessionId deviceId programVersion
                sendAnalytics(sessionId, deviceId, programVersion,'help_button_click');
            catch
            end
            app.HelpApp = Helpv2();
        end

        % Menu selected function: AboutMenu_2
        function AboutMenuScreen(app, event)
            %run About.mlapp;
            try
                sendAnalytics('about_button_click');
            catch
            end
            app.AboutApp = About();
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            CIFRIBUIFigureCloseRequest(app);
        end

        % Callback function
        function threedimensional(app, event)
            try
                fig = uifigure('Name', 'Benutzereingabe 3D', 'Position', [400 400 300 300]);
                uilabel(fig, 'Position', [20 90 260 30], 'Text', 'Eingabe');
                nameField = uieditfield(fig, 'text', 'Position', [20 60 260 22]);
                submitButton = uibutton(fig, 'Text', 'Bestätigen', ...
                    'Position', [100 20 100 30], ...
                    'ButtonPushedFcn', @(btn, event) disp(['Name: ', nameField.Value]));

            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Callback function: CIFRIBUIFigure, CIFRIBUIFigure
        function CIFRIBUIFigureCloseRequest(app, event)
            try
                global main;
                msg = "Do you want to save your project?";
                title = "Close Confirm";
                selection = uiconfirm(app.CIFRIBUIFigure,msg,title, ...
                    "Options",["Close without saving","Save","Cancel"], ...
                    "DefaultOption",2,"CancelOption",3);
                switch selection
                    case 'Close without saving'
                        delete(app.HelpApp)
                        delete(app.AboutApp)
                        delete(app.PreferencesApp)
                        delete(app)
                    case 'Save'
                        uisave({'main'},'myproject1') % insert what to save
                        delete(app.HelpApp)
                        delete(app.AboutApp)
                        delete(app.PreferencesApp)
                        delete(app)
                    case 'Cancel'
                        return
                end
                try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'app_close');
                catch
                end
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
            try
                delete(app.HelpApp)
                delete(app.AboutApp)
            catch
            end
            delete(app)
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            try
                global main
                uisave({'main'},'myproject1') % insert what to save
                global isprojectsaved
                isprojectsaved = true;
                try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'save_button_click');
                catch
                end
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            try
                SaveAsMenuSelected(app);
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            try
                global main
                [file,path] = uigetfile('*.mat', 'Select a saved project');
                % Full file path
                filePath = fullfile(path, file);
                % Load the structure
                %main = load(filePath);
                loadedData = load(filePath); % Load the data into a structure
                if isfield(loadedData, 'main') % Check if 'main' exists in the loaded data
                    main = loadedData.main; % Assign the content of 'main' to the global variable
                    uialert(app.CIFRIBUIFigure, "Project successfully loaded".', ...
                        'Loaded', 'Icon', 'warning');
                else 
                    uialert(app.UIFigure, 'The file does not contain the expected structure "main".', ...
                        'Load Error', 'Icon', 'error');
                    return;
                end
                PlotAll(app);
                try
                    app.Spinner_BarLength.Value = main.BarLgh;
                catch
                end
                try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'open_button_click');
                catch
                end

            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Menu selected function: WebsiteMenu
        function WebsiteMenuSelected(app, event)
            try
                web('www.schnittgroessen.com')
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Key press function: CIFRIBUIFigure
        function CIFRIBUIFigureKeyPress(app, event)
            try
                key = event.Key; % Get the key pressed
                disp(key)
                modifiers = event.Modifier; % Get the modifiers (e.g., 'control', 'shift')
                % Check if 'control' is one of the modifiers
                if ismember('control', modifiers)
                    switch key
                        case 'z'
                            Undo(app); % Call the Undo function
                        case 'y'
                            Redo(app); % Call the Redo function
                    end
                end
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Spinner_SupportPosition
        function Spinner_SupportPositionValueChanged(app, event)
            try
                global main
                value = app.Spinner_SupportPosition.Value;
                % if outside bar, change value to nearest end of bar
                if value < 0
                    app.Spinner_SupportPosition.Value = 0;
                end
                if value > main.BarLgh
                    app.Spinner_SupportPosition.Value = main.BarLgh;
                end
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Spinner_HingePosition
        function Spinner_HingePositionValueChanged(app, event)
            try
                global main
                value = app.Spinner_HingePosition.Value;
                % if outside bar, change value to nearest end of bar
                if value < 0
                    app.Spinner_HingePosition.Value = 0;
                end
                if value > main.BarLgh
                    app.Spinner_HingePosition.Value = main.BarLgh;
                end
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Spinner_PointLoadsPosition
        function Spinner_PointLoadsPositionValueChanged(app, event)
            try
                global main
                value = app.Spinner_PointLoadsPosition.Value;
                % if outside bar, change value to nearest end of bar
                if value < 0
                    app.Spinner_PointLoadsPosition.Value = 0;
                end
                if value > main.BarLgh
                    app.Spinner_PointLoadsPosition.Value = main.BarLgh;
                end
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Spinner_DistributedLoadsStartPosition
        function Spinner_DistributedLoadsStartPositionValueChanged(app, event)
            try
                global main
                value = app.Spinner_DistributedLoadsStartPosition.Value;
                % if outside bar, change value to nearest end of bar
                if value < 0
                    app.Spinner_DistributedLoadsStartPosition.Value = 0;
                end
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Spinner_DistributedLoadsEndPosition
        function Spinner_DistributedLoadsEndPositionValueChanged(app, event)
            try
                global main
                value = app.Spinner_DistributedLoadsEndPosition.Value;
                % if outside bar, change value to nearest end of bar
                if value > main.BarLgh
                    app.Spinner_DistributedLoadsEndPosition.Value = main.BarLgh;
                end
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)
            try
                app.PreferencesApp = Preferences(app);
                try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'preferences_button_click');
                catch
                end
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Menu selected function: PDFMenu
        function PDFMenuSelected(app, event)
            try
                makeDOMCompilable();
                import mlreportgen.dom.*;
                % Ask user to select a save path for the PDF
                [file, path] = uiputfile('*.pdf', 'Save Report As');
                if isequal(file, 0) || isequal(path, 0)
                    % User canceled the save dialog
                    return;
                end
                pdfPath = fullfile(path, file);
                
                % Create a modal dialog (blocking UI)
                d = uifigure('Name', 'Creating Report...', 'Position', [500, 500, 300, 100], 'WindowStyle', 'modal');
                uilabel(d, 'Text', 'The report is being created. Please wait...', ...
                    'Position', [20, 40, 260, 20], 'HorizontalAlignment', 'center');
                
                
                try
                    % Create a PDF document
                    doc = Document(pdfPath, 'pdf');
                    
                    % Add a title
                    title = Paragraph('Calculation Report');
                    title.Style = {Bold(true), FontSize('20pt'), HAlign('center')};
                    append(doc, title);
        
                    % Add empty paragraph after title
                    emptyParagraph = Paragraph(' ');
                    emptyParagraph.Style = {Height('150pt')}; % Add 20 points of spacing
                    append(doc, emptyParagraph);
        
                    % Add setup visualization
                    setupImage = 'setup_image.png';
                    exportgraphics(app.UIAxes_Setup, setupImage, 'Resolution', 300);
                    img1 = Image(setupImage);
                    img1.Style = {Width('6in')};
                    append(doc, img1);
                    append(doc, Paragraph('Setup Visualization'));
        
                    % Add a page break
                    append(doc, PageBreak);
    
                    % Add the first plot (Normal Force Results)
                    normalForcePlotImage = 'normal_force_plot.png';
                    exportgraphics(app.UIAxes_ResultsNormalForce, normalForcePlotImage, 'Resolution', 300);
                    normalForceImg = Image(normalForcePlotImage);
                    normalForceImg.Style = {Width('6in')};
                    append(doc, normalForceImg);
                    append(doc, Paragraph(''));
                    
                    % Add the second plot (Shear Force Results)
                    shearForcePlotImage = 'shear_force_plot.png';
                    exportgraphics(app.UIAxes_ResultsShearForce, shearForcePlotImage, 'Resolution', 300);
                    shearForceImg = Image(shearForcePlotImage);
                    shearForceImg.Style = {Width('6in')};
                    append(doc, shearForceImg);
                    append(doc, Paragraph(''));
    
                    % Add the third plot (Moment Results)
                    momentPlotImage = 'moment_plot.png';
                    exportgraphics(app.UIAxes_ResultsMoment, momentPlotImage, 'Resolution', 300);
                    momentImg = Image(momentPlotImage);
                    momentImg.Style = {Width('6in')};
                    append(doc, momentImg);
                    append(doc, Paragraph(''));
                    
                    % % Add text or table results (if needed)
                    % tableImage = 'table_results.png';
                    % exportgraphics(app.UITable_Results, tableImage, 'Resolution', 300); % Export table if it's meaningful to the report
                    % tableImg = Image(tableImage);
                    % tableImg.Style = {Width('6in')};
                    % append(doc, tableImg);
                    % append(doc, Paragraph('Results Table'));
                    
                    % Add empty paragraph after plots
                    emptyParagraph = Paragraph(' ');
                    emptyParagraph.Style = {Height('50pt')}; % Add 20 points of spacing
                    append(doc, emptyParagraph);
    
                    % Add text description
                    desc = Paragraph('This report was created by CiFRiB_v1.0');
                    desc.Style = {FontSize('10pt')};
                    append(doc, desc);
    
                    % Create a hyperlink
                    link = ExternalLink('https://schnittgroessen.com', 'www.schnittgroessen.com');
                    link.Style = {FontSize('10pt'), Color('blue'), Underline('single')};
                    
                    % Append the hyperlink to the document
                    append(doc, link);
                    
                    % Close and generate the PDF
                    close(doc);
        
                    %clean up the temporary images after pdf creation
                    delete(setupImage, momentPlotImage, shearForcePlotImage, normalForcePlotImage);
                catch e
                    % If an error occurs, show an alert
                    uialert(app.CIFRIBUIFigure, ['Error: ', e.message], 'Error', 'Icon', 'error');
                end
    
                % disable uialert
                delete(d);

                try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'export_button_click');
                catch
                end
    
                %open the pdf
                winopen(pdfPath); % Automatically open the PDF
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Button_BarprofileRektangle
        function Button_BarprofileRektangleValueChanged(app, event)
            try
                app.Button_BarprofileH.Value = false;
                app.Button_BarprofileRound.Value = false;
                app.Button_BarprofileSquarehollow.Value = false;
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Button_BarprofileSquarehollow
        function Button_BarprofileSquarehollowValueChanged(app, event)
            try
                app.Button_BarprofileRektangle.Value = false;
                app.Button_BarprofileH.Value = false;
                app.Button_BarprofileRound.Value = false;
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Button_BarprofileRound
        function Button_BarprofileRoundValueChanged(app, event)
            try
                app.Button_BarprofileH.Value = false;
                app.Button_BarprofileRektangle.Value = false;
                app.Button_BarprofileSquarehollow.Value = false;
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Value changed function: Button_BarprofileH
        function Button_BarprofileHValueChanged(app, event)
            try
                app.Button_BarprofileRektangle.Value = false;
                app.Button_BarprofileRound.Value = false;
                app.Button_BarprofileSquarehollow.Value = false;
            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Menu selected function: Example1Menu
        function Example1MenuSelected(app, event)
            try
                loadProject(app,'example1.mat')
                try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'example1_button_click');
                catch
                end

            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Menu selected function: Example2Menu
        function Example2MenuSelected(app, event)
            try
                loadProject(app,'example2.mat')
                try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'example1_button_click');
                catch
                end

            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Menu selected function: Example3Menu
        function Example3MenuSelected(app, event)
            try
                loadProject(app,'example3.mat')
                try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'example1_button_click');
                catch
                end

            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end

        % Menu selected function: Example4Menu
        function Example4MenuSelected(app, event)
            try
                loadProject(app,'example4.mat')
                try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'example1_button_click');
                catch
                end

            catch errormessage
                 uialert(app.CIFRIBUIFigure, ...
                 errormessage.message, ...
                 'Error', ...
                 'Icon', 'error');
                 try
                    global sessionId deviceId programVersion
                    sendAnalytics(sessionId, deviceId, programVersion,'error', errormessage);
                 catch
                 end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CIFRIBUIFigure = uifigure('Visible', 'off');
            app.CIFRIBUIFigure.Name = 'CIFRIB';
            app.CIFRIBUIFigure.CloseRequestFcn = createCallbackFcn(app, @CIFRIBUIFigureCloseRequest, true);
            app.CIFRIBUIFigure.ButtonDownFcn = createCallbackFcn(app, @CIFRIBUIFigureCloseRequest, true);
            app.CIFRIBUIFigure.KeyPressFcn = createCallbackFcn(app, @CIFRIBUIFigureKeyPress, true);
            app.FileMenu = uimenu(app.CIFRIBUIFigure);
            app.FileMenu.Text = 'File';
            app.Menu_New = uimenu(app.FileMenu);
            app.Menu_New.MenuSelectedFcn = createCallbackFcn(app, @Menu_NewSelected, true);
            app.Menu_New.Text = 'New...';
            app.ExamplesMenu = uimenu(app.FileMenu);
            app.ExamplesMenu.Text = 'Examples';
            app.Example1Menu = uimenu(app.ExamplesMenu);
            app.Example1Menu.MenuSelectedFcn = createCallbackFcn(app, @Example1MenuSelected, true);
            app.Example1Menu.Text = 'Example 1';
            app.Example2Menu = uimenu(app.ExamplesMenu);
            app.Example2Menu.MenuSelectedFcn = createCallbackFcn(app, @Example2MenuSelected, true);
            app.Example2Menu.Text = 'Example 2';
            app.Example3Menu = uimenu(app.ExamplesMenu);
            app.Example3Menu.MenuSelectedFcn = createCallbackFcn(app, @Example3MenuSelected, true);
            app.Example3Menu.Text = 'Example 3';
            app.Example4Menu = uimenu(app.ExamplesMenu);
            app.Example4Menu.MenuSelectedFcn = createCallbackFcn(app, @Example4MenuSelected, true);
            app.Example4Menu.Text = 'Example 4';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As...';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.PDFMenu = uimenu(app.ExportMenu);
            app.PDFMenu.MenuSelectedFcn = createCallbackFcn(app, @PDFMenuSelected, true);
            app.PDFMenu.Text = 'PDF';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.CIFRIBUIFigure);
            app.EditMenu.Text = 'Edit';
            app.Menu_Undo = uimenu(app.EditMenu);
            app.Menu_Undo.MenuSelectedFcn = createCallbackFcn(app, @Menu_UndoSelected, true);
            app.Menu_Undo.Text = 'Undo';
            app.Menu_Redo = uimenu(app.EditMenu);
            app.Menu_Redo.MenuSelectedFcn = createCallbackFcn(app, @Menu_RedoSelected, true);
            app.Menu_Redo.Text = 'Redo';
            app.PreferencesMenu = uimenu(app.EditMenu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Text = 'Preferences';
            app.HelpMenu = uimenu(app.CIFRIBUIFigure);
            app.HelpMenu.Text = 'Help';
            app.UserManualMenu = uimenu(app.HelpMenu);
            app.UserManualMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuScreen, true);
            app.UserManualMenu.Text = 'User Manual';
            app.AboutMenu = uimenu(app.CIFRIBUIFigure);
            app.AboutMenu.Text = 'About';
            app.AboutMenu_2 = uimenu(app.AboutMenu);
            app.AboutMenu_2.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuScreen, true);
            app.AboutMenu_2.Text = 'About';
            app.WebsiteMenu = uimenu(app.AboutMenu);
            app.WebsiteMenu.MenuSelectedFcn = createCallbackFcn(app, @WebsiteMenuSelected, true);
            app.WebsiteMenu.Text = 'Website';
            app.GridLayout12 = uigridlayout(app.CIFRIBUIFigure);
            app.TabGroup_MainGui = uitabgroup(app.GridLayout12);
            app.Tab_Setup = uitab(app.TabGroup_MainGui);
            app.Tab_Setup.Title = 'Setup';
            app.GridLayout11 = uigridlayout(app.Tab_Setup);
            app.LeftPanel = uipanel(app.GridLayout11);
            app.GridLayout5 = uigridlayout(app.LeftPanel);
            app.BarSetupPanel = uipanel(app.GridLayout5);
            app.BarSetupPanel.Title = 'Bar Setup';
            app.GridLayout6 = uigridlayout(app.BarSetupPanel);
            app.TabGroup3 = uitabgroup(app.GridLayout6);
            app.BarTab = uitab(app.TabGroup3);
            app.BarTab.Title = 'Bar';
            app.GridLayout18 = uigridlayout(app.BarTab);
            app.Panel_6 = uipanel(app.GridLayout18);
            app.GridLayout19 = uigridlayout(app.Panel_6);
            app.LengthSpinnerLabel = uilabel(app.GridLayout19);
            app.LengthSpinnerLabel.Text = 'Length';
            app.Spinner_BarLength = uispinner(app.GridLayout19);
            app.Spinner_BarLength.Limits = [0 Inf];
            app.Spinner_BarLength.ValueDisplayFormat = '%11.4g m';
            app.Spinner_BarLength.ValueChangedFcn = createCallbackFcn(app, @Spinner_BarLengthValueChanged, true);
            app.Spinner_BarLength.Tag = 'Bar_Length';
            app.MaterialDropDownLabel = uilabel(app.GridLayout19);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.GridLayout19);
            app.MaterialDropDown.Items = {'Standard', 'S275JR', 'E335', 'AW-6061 T4'};
            app.MaterialDropDown.Value = 'Standard';
            app.SteelDropDownLabel = uilabel(app.GridLayout19);
            app.SteelDropDownLabel.Enable = 'off';
            app.SteelDropDownLabel.Visible = 'off';
            app.SteelDropDownLabel.Text = 'Steel';
            app.SteelDropDown = uidropdown(app.GridLayout19);
            app.SteelDropDown.Items = {'Standard', 'E335'};
            app.SteelDropDown.Enable = 'off';
            app.SteelDropDown.Visible = 'off';
            app.SteelDropDown.Value = 'Standard';
            app.Panel_17 = uipanel(app.GridLayout18);
            app.GridLayout34 = uigridlayout(app.Panel_17);
            app.Button_BarAdd = uibutton(app.GridLayout34, 'push');
            app.Button_BarAdd.ButtonPushedFcn = createCallbackFcn(app, @Button_BarAddPushed, true);
            app.Button_BarAdd.Text = 'Add';
            app.BarprofileTab = uitab(app.TabGroup3);
            app.BarprofileTab.Title = 'Bar profile';
            app.GridLayout36 = uigridlayout(app.BarprofileTab);
            app.Panel_18 = uipanel(app.GridLayout36);
            app.GridLayout37 = uigridlayout(app.Panel_18);
            app.Button_BarprofileRektangle = uibutton(app.GridLayout37, 'state');
            app.Button_BarprofileRektangle.ValueChangedFcn = createCallbackFcn(app, @Button_BarprofileRektangleValueChanged, true);
            app.Button_BarprofileRektangle.Icon = fullfile(pathToMLAPP, 'Icons', 'profile_rectangle.png');
            app.Button_BarprofileRektangle.Text = '';
            app.Button_BarprofileSquarehollow = uibutton(app.GridLayout37, 'state');
            app.Button_BarprofileSquarehollow.ValueChangedFcn = createCallbackFcn(app, @Button_BarprofileSquarehollowValueChanged, true);
            app.Button_BarprofileSquarehollow.Icon = fullfile(pathToMLAPP, 'Icons', 'profile_square.png');
            app.Button_BarprofileSquarehollow.Text = '';
            app.Button_BarprofileRound = uibutton(app.GridLayout37, 'state');
            app.Button_BarprofileRound.ValueChangedFcn = createCallbackFcn(app, @Button_BarprofileRoundValueChanged, true);
            app.Button_BarprofileRound.Icon = fullfile(pathToMLAPP, 'Icons', 'profile_round.png');
            app.Button_BarprofileRound.Text = '';
            app.Button_BarprofileH = uibutton(app.GridLayout37, 'state');
            app.Button_BarprofileH.ValueChangedFcn = createCallbackFcn(app, @Button_BarprofileHValueChanged, true);
            app.Button_BarprofileH.Icon = fullfile(pathToMLAPP, 'Icons', 'profile_H.png');
            app.Button_BarprofileH.Text = '';
            app.Panel_19 = uipanel(app.GridLayout36);
            app.GridLayout38 = uigridlayout(app.Panel_19);
            app.Spinner_BarprofileB = uispinner(app.GridLayout38);
            app.Spinner_BarprofileB.Limits = [0 Inf];
            app.Spinner_BarprofileB.ValueDisplayFormat = '%11.4g mm';
            app.Spinner_BarprofileB.AllowEmpty = 'on';
            app.Spinner_BarprofileB.Tooltip = {'B in mm'};
            app.Spinner_BarprofileB.Placeholder = 'B  ';
            app.Spinner_BarprofileB.Value = [];
            app.Spinner_BarprofileA = uispinner(app.GridLayout38);
            app.Spinner_BarprofileA.Limits = [0 Inf];
            app.Spinner_BarprofileA.ValueDisplayFormat = '%11.4g mm';
            app.Spinner_BarprofileA.AllowEmpty = 'on';
            app.Spinner_BarprofileA.Tooltip = {'A in mm'};
            app.Spinner_BarprofileA.Placeholder = 'A  ';
            app.Spinner_BarprofileA.Value = [];
            app.SupportsPanel = uipanel(app.GridLayout5);
            app.SupportsPanel.Title = 'Supports';
            app.GridLayout8 = uigridlayout(app.SupportsPanel);
            app.TabGroup2 = uitabgroup(app.GridLayout8);
            app.SupportsTab = uitab(app.TabGroup2);
            app.SupportsTab.Title = 'Supports';
            app.GridLayout20 = uigridlayout(app.SupportsTab);
            app.Panel_7 = uipanel(app.GridLayout20);
            app.GridLayout22 = uigridlayout(app.Panel_7);
            app.Button_SupportLooseBearing = uibutton(app.GridLayout22, 'state');
            app.Button_SupportLooseBearing.ValueChangedFcn = createCallbackFcn(app, @Button_SupportLooseBearingValueChanged, true);
            app.Button_SupportLooseBearing.Icon = fullfile(pathToMLAPP, 'icons', 'loose_bearing.png');
            app.Button_SupportLooseBearing.Text = '';
            app.Button_SupportFixedBearing = uibutton(app.GridLayout22, 'state');
            app.Button_SupportFixedBearing.ValueChangedFcn = createCallbackFcn(app, @Button_SupportFixedBearingValueChanged, true);
            app.Button_SupportFixedBearing.Icon = fullfile(pathToMLAPP, 'icons', 'fixed_bearing.png');
            app.Button_SupportFixedBearing.Text = '';
            app.Button_SupportClampedSupport = uibutton(app.GridLayout22, 'state');
            app.Button_SupportClampedSupport.ValueChangedFcn = createCallbackFcn(app, @Button_SupportClampedSupportValueChanged, true);
            app.Button_SupportClampedSupport.Icon = fullfile(pathToMLAPP, 'icons', 'clamped_support.png');
            app.Button_SupportClampedSupport.Text = '';
            app.Panel_8 = uipanel(app.GridLayout20);
            app.GridLayout21 = uigridlayout(app.Panel_8);
            app.PositionSpinnerLabel = uilabel(app.GridLayout21);
            app.PositionSpinnerLabel.Text = 'Position';
            app.Spinner_SupportPosition = uispinner(app.GridLayout21);
            app.Spinner_SupportPosition.ValueDisplayFormat = '%11.4g m';
            app.Spinner_SupportPosition.ValueChangedFcn = createCallbackFcn(app, @Spinner_SupportPositionValueChanged, true);
            app.Button_SupportAdd = uibutton(app.GridLayout20, 'push');
            app.Button_SupportAdd.ButtonPushedFcn = createCallbackFcn(app, @Button_SupportAddPushed, true);
            app.Button_SupportAdd.Text = 'Add';
            app.HingesTab = uitab(app.TabGroup2);
            app.HingesTab.Title = 'Hinges';
            app.GridLayout23 = uigridlayout(app.HingesTab);
            app.Panel_10 = uipanel(app.GridLayout23);
            app.GridLayout32 = uigridlayout(app.Panel_10);
            app.Button_HingeMoment = uibutton(app.GridLayout32, 'state');
            app.Button_HingeMoment.ValueChangedFcn = createCallbackFcn(app, @Button_HingeMomentValueChanged, true);
            app.Button_HingeMoment.Icon = fullfile(pathToMLAPP, 'icons', 'hinge.png');
            app.Button_HingeMoment.Text = '';
            app.Button_HingeShearforce = uibutton(app.GridLayout32, 'state');
            app.Button_HingeShearforce.ValueChangedFcn = createCallbackFcn(app, @Button_HingeShearforceValueChanged, true);
            app.Button_HingeShearforce.Tooltip = {''};
            app.Button_HingeShearforce.Icon = fullfile(pathToMLAPP, 'icons', 'shearforcehinge.png');
            app.Button_HingeShearforce.Text = '';
            app.Button_HingeNormalforce = uibutton(app.GridLayout32, 'state');
            app.Button_HingeNormalforce.ValueChangedFcn = createCallbackFcn(app, @Button_HingeNormalforceValueChanged, true);
            app.Button_HingeNormalforce.Tooltip = {''};
            app.Button_HingeNormalforce.Icon = fullfile(pathToMLAPP, 'icons', 'normalforcehinge.png');
            app.Button_HingeNormalforce.Text = '';
            app.Panel_11 = uipanel(app.GridLayout23);
            app.GridLayout25 = uigridlayout(app.Panel_11);
            app.PositionSpinner_2Label = uilabel(app.GridLayout25);
            app.PositionSpinner_2Label.Text = 'Position';
            app.Spinner_HingePosition = uispinner(app.GridLayout25);
            app.Spinner_HingePosition.ValueDisplayFormat = '%11.4g m';
            app.Spinner_HingePosition.ValueChangedFcn = createCallbackFcn(app, @Spinner_HingePositionValueChanged, true);
            app.Button_HingeAdd = uibutton(app.GridLayout23, 'push');
            app.Button_HingeAdd.ButtonPushedFcn = createCallbackFcn(app, @Button_HingeAddPushed, true);
            app.Button_HingeAdd.Text = 'Add';
            app.Panel_Loads = uipanel(app.GridLayout5);
            app.Panel_Loads.Title = 'Loads';
            app.GridLayout9 = uigridlayout(app.Panel_Loads);
            app.TabGroupForce = uitabgroup(app.GridLayout9);
            app.PointloadsTab = uitab(app.TabGroupForce);
            app.PointloadsTab.Title = 'Point loads';
            app.GridLayout26 = uigridlayout(app.PointloadsTab);
            app.Panel_13 = uipanel(app.GridLayout26);
            app.GridLayout28 = uigridlayout(app.Panel_13);
            app.Button_PointLoadsForce = uibutton(app.GridLayout28, 'state');
            app.Button_PointLoadsForce.ValueChangedFcn = createCallbackFcn(app, @Button_PointLoadsForceValueChanged, true);
            app.Button_PointLoadsForce.Icon = fullfile(pathToMLAPP, 'icons', 'force.png');
            app.Button_PointLoadsForce.Text = '';
            app.Button_PointloadsMoment = uibutton(app.GridLayout28, 'state');
            app.Button_PointloadsMoment.ValueChangedFcn = createCallbackFcn(app, @Button_PointloadsMomentValueChanged, true);
            app.Button_PointloadsMoment.Icon = fullfile(pathToMLAPP, 'icons', 'moment.png');
            app.Button_PointloadsMoment.Text = '';
            app.Panel_12 = uipanel(app.GridLayout26);
            app.GridLayout27 = uigridlayout(app.Panel_12);
            app.PositionSpinner_3Label = uilabel(app.GridLayout27);
            app.PositionSpinner_3Label.Text = 'Position';
            app.Spinner_PointLoadsPosition = uispinner(app.GridLayout27);
            app.Spinner_PointLoadsPosition.ValueDisplayFormat = '%11.4g m';
            app.Spinner_PointLoadsPosition.ValueChangedFcn = createCallbackFcn(app, @Spinner_PointLoadsPositionValueChanged, true);
            app.ForceSpinnerLabel = uilabel(app.GridLayout27);
            app.ForceSpinnerLabel.Text = 'Force';
            app.Spinner_PointLoadsForce = uispinner(app.GridLayout27);
            app.Spinner_PointLoadsForce.ValueDisplayFormat = '%11.4g N';
            app.Spinner_PointLoadsForce.Tooltip = {''};
            app.AngleSpinnerLabel = uilabel(app.GridLayout27);
            app.AngleSpinnerLabel.Text = 'Angle';
            app.Spinner_PointLoadsAngle = uispinner(app.GridLayout27);
            app.Spinner_PointLoadsAngle.ValueDisplayFormat = '%11.4g °';
            app.Spinner_PointLoadsAngle.Tooltip = {'0° = <-'};
            app.Spinner_PointLoadsAngle.Value = 90;
            app.Button_PointloadsAdd = uibutton(app.GridLayout26, 'push');
            app.Button_PointloadsAdd.ButtonPushedFcn = createCallbackFcn(app, @Button_PointloadsAddPushed, true);
            app.Button_PointloadsAdd.Text = 'Add';
            app.DistributedloadsTab = uitab(app.TabGroupForce);
            app.DistributedloadsTab.Title = 'Distributed loads';
            app.GridLayout26_2 = uigridlayout(app.DistributedloadsTab);
            app.Panel_15 = uipanel(app.GridLayout26_2);
            app.GridLayout30 = uigridlayout(app.Panel_15);
            app.Button_DistributedLoadsConstant = uibutton(app.GridLayout30, 'state');
            app.Button_DistributedLoadsConstant.ValueChangedFcn = createCallbackFcn(app, @Button_DistributedLoadsConstantValueChanged, true);
            app.Button_DistributedLoadsConstant.Icon = fullfile(pathToMLAPP, 'icons', 'constant_load.png');
            app.Button_DistributedLoadsConstant.Text = '';
            app.Button_DistributedLoadsLinear = uibutton(app.GridLayout30, 'state');
            app.Button_DistributedLoadsLinear.ValueChangedFcn = createCallbackFcn(app, @Button_DistributedLoadsLinearValueChanged, true);
            app.Button_DistributedLoadsLinear.Icon = fullfile(pathToMLAPP, 'icons', 'linear_load.png');
            app.Button_DistributedLoadsLinear.Text = '';
            app.Button_DistributedLoadsQuadratic = uibutton(app.GridLayout30, 'state');
            app.Button_DistributedLoadsQuadratic.ValueChangedFcn = createCallbackFcn(app, @Button_DistributedLoadsQuadraticValueChanged, true);
            app.Button_DistributedLoadsQuadratic.Icon = fullfile(pathToMLAPP, 'icons', 'quadratic_load.png');
            app.Button_DistributedLoadsQuadratic.Text = '';
            app.Panel_14 = uipanel(app.GridLayout26_2);
            app.GridLayout29 = uigridlayout(app.Panel_14);
            app.StartPositionSpinnerLabel = uilabel(app.GridLayout29);
            app.StartPositionSpinnerLabel.Text = 'Start Position';
            app.Spinner_DistributedLoadsStartPosition = uispinner(app.GridLayout29);
            app.Spinner_DistributedLoadsStartPosition.ValueDisplayFormat = '%11.4g m';
            app.Spinner_DistributedLoadsStartPosition.ValueChangedFcn = createCallbackFcn(app, @Spinner_DistributedLoadsStartPositionValueChanged, true);
            app.EndPositionSpinnerLabel = uilabel(app.GridLayout29);
            app.EndPositionSpinnerLabel.Text = 'End Position';
            app.Spinner_DistributedLoadsEndPosition = uispinner(app.GridLayout29);
            app.Spinner_DistributedLoadsEndPosition.ValueDisplayFormat = '%11.4g m';
            app.Spinner_DistributedLoadsEndPosition.ValueChangedFcn = createCallbackFcn(app, @Spinner_DistributedLoadsEndPositionValueChanged, true);
            app.StartValueSpinnerLabel = uilabel(app.GridLayout29);
            app.StartValueSpinnerLabel.Text = 'Start Value';
            app.Spinner_DistributedLoadsStartValue = uispinner(app.GridLayout29);
            app.Spinner_DistributedLoadsStartValue.ValueDisplayFormat = '%11.4g N/m';
            app.PitchSpinnerLabel = uilabel(app.GridLayout29);
            app.PitchSpinnerLabel.Text = 'Pitch';
            app.Spinner_DistributedLoadsPitch = uispinner(app.GridLayout29);
            app.Spinner_DistributedLoadsPitch.ValueDisplayFormat = '%11.4g ';
            app.Button_DistributedLoadsAdd = uibutton(app.GridLayout26_2, 'push');
            app.Button_DistributedLoadsAdd.ButtonPushedFcn = createCallbackFcn(app, @Button_DistributedLoadsAddPushed, true);
            app.Button_DistributedLoadsAdd.Text = 'Add';
            app.Button_SetupCalculate = uibutton(app.GridLayout5, 'push');
            app.Button_SetupCalculate.ButtonPushedFcn = createCallbackFcn(app, @Button_SetupCalculatePushed, true);
            app.Button_SetupCalculate.Text = 'Calculate!';
            app.Panel_2 = uipanel(app.GridLayout11);
            app.GridLayout13 = uigridlayout(app.Panel_2);
            app.Panel_5 = uipanel(app.GridLayout13);
            app.GridLayout31 = uigridlayout(app.Panel_5);
            app.UIAxes_Setup = uiaxes(app.GridLayout31);
            title(app.UIAxes_Setup, 'Setup')
            xlabel(app.UIAxes_Setup, 'X')
            ylabel(app.UIAxes_Setup, 'Z')
            app.UIAxes_Setup.YDir = 'reverse';
            app.Panel = uipanel(app.GridLayout13);
            app.GridLayout10 = uigridlayout(app.Panel);
            app.AngledegEditFieldLabel = uilabel(app.GridLayout10);
            app.AngledegEditFieldLabel.Text = '[Angle] deg';
            app.AngledegEditField = uieditfield(app.GridLayout10, 'numeric');
            app.PositionmLabel = uilabel(app.GridLayout10);
            app.PositionmLabel.Text = '[Position] m';
            app.PositionmEditField = uieditfield(app.GridLayout10, 'numeric');
            app.ForceNNmEditFieldLabel = uilabel(app.GridLayout10);
            app.ForceNNmEditFieldLabel.Text = '[Force] N N/m';
            app.ForceNNmEditField = uieditfield(app.GridLayout10, 'numeric');
            app.DeleteViewButton = uibutton(app.GridLayout10, 'state');
            app.DeleteViewButton.Tooltip = {''};
            app.DeleteViewButton.Text = 'Delete/View';
            app.EditField_StaticDefinition1 = uieditfield(app.GridLayout10, 'numeric');
            app.EditField_StaticDefinition1.Editable = 'off';
            app.Label = uilabel(app.GridLayout10);
            app.Label.Text = '=';
            app.EditField_StaticDefinition2 = uieditfield(app.GridLayout10, 'numeric');
            app.EditField_StaticDefinition2.Editable = 'off';
            app.Lamp_StaticDefinition = uilamp(app.GridLayout10);
            app.Tab_Results = uitab(app.TabGroup_MainGui);
            app.Tab_Results.Title = 'Results';
            app.GridLayout14 = uigridlayout(app.Tab_Results);
            app.Panel_3 = uipanel(app.GridLayout14);
            app.GridLayout15 = uigridlayout(app.Panel_3);
            app.UIAxes_ResultsNormalForce = uiaxes(app.GridLayout15);
            title(app.UIAxes_ResultsNormalForce, 'Normal-Force')
            xlabel(app.UIAxes_ResultsNormalForce, 'X')
            ylabel(app.UIAxes_ResultsNormalForce, 'Z')
            app.UIAxes_ResultsShearForce = uiaxes(app.GridLayout15);
            title(app.UIAxes_ResultsShearForce, 'Shear-Force')
            xlabel(app.UIAxes_ResultsShearForce, 'X')
            ylabel(app.UIAxes_ResultsShearForce, 'Z')
            app.UIAxes_ResultsMoment = uiaxes(app.GridLayout15);
            title(app.UIAxes_ResultsMoment, 'Moment')
            xlabel(app.UIAxes_ResultsMoment, 'X')
            ylabel(app.UIAxes_ResultsMoment, 'Z')
            app.Panel_4 = uipanel(app.GridLayout14);
            app.GridLayout16 = uigridlayout(app.Panel_4);
            app.BearingReactionsPanel = uipanel(app.GridLayout16);
            app.BearingReactionsPanel.Title = 'Bearing Reactions';
            app.GridLayout33 = uigridlayout(app.BearingReactionsPanel);
            app.UITable_Results = uitable(app.GridLayout33);
            app.UITable_Results.ColumnName = {'Index'; '[Normal-Force] N'; '[Shear-Force] N'; '[Moment] Nm'};
            app.UITable_Results.RowName = {};
            app.UITable_Results.ColumnSortable = true;
            app.CalculateForcesataspecificpointPanel = uipanel(app.GridLayout16);
            app.CalculateForcesataspecificpointPanel.Title = 'Calculate Forces at a specific point';
            app.GridLayout17 = uigridlayout(app.CalculateForcesataspecificpointPanel);
            app.PositionEditFieldLabel = uilabel(app.GridLayout17);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.GridLayout17, 'numeric');
            app.PositionEditField.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldValueChanged, true);
            app.XForceEditFieldLabel = uilabel(app.GridLayout17);
            app.XForceEditFieldLabel.Text = 'X-Force';
            app.XForceEditField = uieditfield(app.GridLayout17, 'numeric');
            app.XForceEditField.Editable = 'off';
            app.ZForceEditFieldLabel = uilabel(app.GridLayout17);
            app.ZForceEditFieldLabel.Text = 'Z-Force';
            app.ZForceEditField = uieditfield(app.GridLayout17, 'numeric');
            app.ZForceEditField.Editable = 'off';
            app.MomentEditFieldLabel = uilabel(app.GridLayout17);
            app.MomentEditFieldLabel.Text = 'Moment';
            app.MomentEditField = uieditfield(app.GridLayout17, 'numeric');
            app.MomentEditField.Editable = 'off';
            app.Panel_20 = uipanel(app.GridLayout16);
            app.GridLayout39 = uigridlayout(app.Panel_20);
            app.SumofsquarederrorsPanel = uipanel(app.GridLayout39);
            app.SumofsquarederrorsPanel.Title = 'Sum of squared errors';
            app.SumofsquarederrorsPanel.Visible = 'off';
            app.GridLayout40 = uigridlayout(app.SumofsquarederrorsPanel);
            app.EditField_ResultsSumofsquarederrors = uieditfield(app.GridLayout40, 'numeric');
            app.EditField_ResultsSumofsquarederrors.AllowEmpty = 'on';
            app.EditField_ResultsSumofsquarederrors.Editable = 'off';
            app.AdditionalDetailsPanel = uipanel(app.GridLayout39);
            app.AdditionalDetailsPanel.Title = 'Additional Details';
            app.GridLayout35 = uigridlayout(app.AdditionalDetailsPanel);
            app.SafetyfractureEditFieldLabel = uilabel(app.GridLayout35);
            app.SafetyfractureEditFieldLabel.Text = 'Safety fracture';
            app.SafetyfractureEditField = uieditfield(app.GridLayout35, 'numeric');
            app.SafetyfractureEditField.Editable = 'off';
            app.SafetyyieldingEditFieldLabel = uilabel(app.GridLayout35);
            app.SafetyyieldingEditFieldLabel.Text = 'Safety yielding';
            app.SafetyyieldingEditField = uieditfield(app.GridLayout35, 'numeric');
            app.SafetyyieldingEditField.Editable = 'off';
            % Show the figure after all components are created
            app.CIFRIBUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CIFRIB_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CIFRIBUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CIFRIBUIFigure)
        end
    end
end