classdef Eingabe3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        DEingabePanel          matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        Panel_5                matlab.ui.container.Panel
        GridLayout11           matlab.ui.container.GridLayout
        TextArea               matlab.ui.control.TextArea
        Label_2                matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        Panel_4                matlab.ui.container.Panel
        GridLayout10           matlab.ui.container.GridLayout
        EditField3             matlab.ui.control.NumericEditField
        EditField3Label        matlab.ui.control.Label
        EditField2             matlab.ui.control.NumericEditField
        EditField2Label        matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        EditFieldLabel         matlab.ui.control.Label
        WinkeldefinierenPanel  matlab.ui.container.Panel
        GridLayout8            matlab.ui.container.GridLayout
        Panel_3                matlab.ui.container.Panel
        GridLayout9            matlab.ui.container.GridLayout
        XAxesEditField_2       matlab.ui.control.NumericEditField
        Label                  matlab.ui.control.Label
        YAxesEditField_2       matlab.ui.control.NumericEditField
        YAxesEditField_2Label  matlab.ui.control.Label
        ZAxesEditField_2       matlab.ui.control.NumericEditField
        ZAxesEditField_2Label  matlab.ui.control.Label
        AddButton_2            matlab.ui.control.Button
        ElementwhlenPanel      matlab.ui.container.Panel
        GridLayout7            matlab.ui.container.GridLayout
        fixedButton            matlab.ui.control.StateButton
        ForceButton            matlab.ui.control.StateButton
        looseButton            matlab.ui.control.StateButton
        BeamButton             matlab.ui.control.StateButton
        PunktauswhlenPanel     matlab.ui.container.Panel
        GridLayout6            matlab.ui.container.GridLayout
        ListBox                matlab.ui.control.ListBox
        PunktdefinierenPanel   matlab.ui.container.Panel
        GridLayout4            matlab.ui.container.GridLayout
        AddButton              matlab.ui.control.Button
        Panel_2                matlab.ui.container.Panel
        GridLayout5            matlab.ui.container.GridLayout
        ZAxesEditField         matlab.ui.control.NumericEditField
        ZAxesEditFieldLabel    matlab.ui.control.Label
        YAxesEditField         matlab.ui.control.NumericEditField
        YAxesEditFieldLabel    matlab.ui.control.Label
        XAxesEditField         matlab.ui.control.NumericEditField
        XAxesEditFieldLabel    matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        n = 1;
        PointsMatrix = [];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Disp3d(app)
                % hold(app.UIAxes, "on");
                % % Definiere die Eckpunkte des Buchstabens "K"
                % % Jeder Punkt ist ein Tripel [X, Y, Z]
                % X = [0, 0, 0.5, 0.5, 0, 0, 0,0,0,0]; % X-Koordinaten
                % Y = [0, 0.3, 0.3, 0.6, 0.6,1,1,1.5,1.5,2]; % Y-Koordinaten
                % Z = [0, 0, 0, 0, 0, 0, 0.5, 0.5,0,0,]; % Z-Koordinaten (alle auf Z=0 für 2D in 3D-Raum)
                % 
                % % Zeichne die verbundenen Linien
                % plot3(app.UIAxes, X, Y, Z, 'LineWidth', 4, 'Color', 'k');
                % 
                % % Setze die Achsen-Eigenschaften für bessere Darstellung
                % grid(app.UIAxes, 'on'); % Gitter aktivieren
                % axis(app.UIAxes, 'equal'); % Gleiche Achsenskalierung
                % view(app.UIAxes, 3); % 3D-Ansicht aktivieren
                % 
                % % Parameter
                % arrow2_length = 2;
                % arrow2scale = ((2 / arrow2_length) / 10); % Länge des Pfeils = 1/10 der Balkenlänge
                % arrow2phi = deg2rad(180 - 180);
                % 
                % % Pfeilkoordinaten in 2D
                % TextArrow2X = (-6 / 4) * arrow2_length;
                % TextArrow2Y = (-2 / 4) * arrow2_length;
                % TextArrow2Z = 0; % Z-Komponente des Texts
                % 
                % Line2X = [(-4 / 4) (-1 / 4)] * arrow2_length; % Linie des Pfeils
                % Line2Y = [0 0];                              % Linie des Pfeils
                % Line2Z = [0 0];                              % Z-Komponente (flach auf XY)
                % 
                % Arrow2X = [(-1 / 4) (-1 / 4) 0 (-1 / 4) (-1 / 4)]; % Spitze des Pfeils
                % Arrow2Y = [0 (1 / 4) 0 (-1 / 4) 0];               % Spitze des Pfeils
                % Arrow2Z = [0 0 0 0 0];                            % Z-Komponente der Spitze (flach)
                % 
                % % Rotation um Z-Achse
                % Arrow2R = [cos(arrow2phi), -sin(arrow2phi), 0; ...
                %            sin(arrow2phi),  cos(arrow2phi), 0; ...
                %            0,               0,              1];
                % 
                % % 2D-Koordinaten zu 3D erweitern
                % Arrow2 = [TextArrow2X, Line2X, Arrow2X; ...
                %           TextArrow2Y, Line2Y, Arrow2Y; ...
                %           TextArrow2Z, Line2Z, Arrow2Z] * arrow2scale;
                % 
                % % Drehung und Verschiebung
                % Arrow2Rotated = Arrow2R * Arrow2;
                % Arrow2XRotated = Arrow2Rotated(1,:) + 0; % Verschiebung in X
                % Arrow2YRotated = Arrow2Rotated(2,:) + 1.2; % Verschiebung in Y
                % Arrow2ZRotated = Arrow2Rotated(3,:) + 0.5; % Verschiebung in Z
                % 
                % % Plot Pfeil in 3D
                % plot3(app.UIAxes, Arrow2XRotated(2:8), Arrow2YRotated(2:8), Arrow2ZRotated(2:8), 'LineWidth', 2, 'Color', 'r');
                % 
                % % Beschriftung in 3D
                % text(app.UIAxes, Arrow2XRotated(1), Arrow2YRotated(1), Arrow2ZRotated(1), ...
                %     "F = " + "1" + " N", 'FontSize', 15, 'Color', 'r');
                % 
                % % Achseneinstellungen
                % grid(app.UIAxes, 'on');
                % axis(app.UIAxes, 'equal');
                % view(app.UIAxes, 3); % Setzt 3D-Ansicht
                % 
                % 


        end

        % Button pushed function: AddButton
        function AddPointButtonPushed(app, event)
                hold(app.UIAxes, "on");

                X = [app.XAxesEditField.Value,app.XAxesEditField.Value]; 
                Y = [app.YAxesEditField.Value,app.YAxesEditField.Value]; 
                Z = [app.ZAxesEditField.Value,app.ZAxesEditField.Value];
            
                plot3(app.UIAxes, X, Y, Z, 'o', 'MarkerSize', 8, 'MarkerFaceColor', 'k', 'MarkerEdgeColor', 'k');
                
                % Offset für den Text (angepasster Abstand)
                xOffset = 0.1; % Abstand entlang der X-Achse
                yOffset = 0.1; % Abstand entlang der Y-Achse
                zOffset = 0.1; % Abstand entlang der Z-Achse

                text(app.UIAxes, X + xOffset, Y + yOffset, Z + zOffset, ...
                "P" + app.n, 'FontSize', 15, 'Color', 'k');

                % Automatische Skalierung sicherstellen
                app.UIAxes.XLimMode = 'auto';
                app.UIAxes.YLimMode = 'auto';
                app.UIAxes.ZLimMode = 'auto';

                grid(app.UIAxes, 'on'); 
                axis(app.UIAxes, 'equal'); 
                view(app.UIAxes, 3);
                
                % Koordinaten in die Matrix einfügen
                newPoint = [app.XAxesEditField.Value, app.YAxesEditField.Value, app.ZAxesEditField.Value];
                app.PointsMatrix = [app.PointsMatrix; newPoint]; % Matrix erweitern
                % Hole die aktuellen Items
                currentItems = app.ListBox.Items;

                % Füge ein neues Item hinzu
                newItem = "Point " + app.n + " ( " + app.XAxesEditField.Value + " | " + app.YAxesEditField.Value + ...
                    " | " + app.ZAxesEditField.Value + " )";
                app.ListBox.Items = [currentItems, newItem];
                app.n = app.n + 1;
                app.TextArea.Value = cellstr(num2str(app.PointsMatrix));

        end

        % Button pushed function: AddButton_2
        function AddItemButtonPushed(app, event)
            if app.ForceButton.Value == true

                % Parameter
                arrow2_length = 2;
                arrow2scale = ((2 / arrow2_length) / 10); % Länge des Pfeils = 1/10 der Balkenlänge
                arrow2phi = deg2rad(app.XAxesEditField_2.Value - 180);

                % Pfeilkoordinaten in 2D
                TextArrow2X = (-6 / 4) * arrow2_length;
                TextArrow2Y = (-2 / 4) * arrow2_length;
                TextArrow2Z = 0; % Z-Komponente des Texts

                Line2X = [(-4 / 4) (-1 / 4)] * arrow2_length; % Linie des Pfeils
                Line2Y = [0 0];                              % Linie des Pfeils
                Line2Z = [0 0];                              % Z-Komponente (flach auf XY)

                Arrow2X = [(-1 / 4) (-1 / 4) 0 (-1 / 4) (-1 / 4)]; % Spitze des Pfeils
                Arrow2Y = [0 (1 / 4) 0 (-1 / 4) 0];               % Spitze des Pfeils
                Arrow2Z = [0 0 0 0 0];                            % Z-Komponente der Spitze (flach)

                % Rotation um Z-Achse
                Arrow2R = [cos(arrow2phi), -sin(arrow2phi), 0; ...
                           sin(arrow2phi),  cos(arrow2phi), 0; ...
                           0,               0,              1];

                % 2D-Koordinaten zu 3D erweitern
                Arrow2 = [TextArrow2X, Line2X, Arrow2X; ...
                          TextArrow2Y, Line2Y, Arrow2Y; ...
                          TextArrow2Z, Line2Z, Arrow2Z] * arrow2scale;

                % Drehung und Verschiebung
                Arrow2Rotated = Arrow2R * Arrow2;
                Arrow2XRotated = Arrow2Rotated(1,:) + 0.5; % Verschiebung in X
                Arrow2YRotated = Arrow2Rotated(2,:) + 0.4; % Verschiebung in Y
                Arrow2ZRotated = Arrow2Rotated(3,:) + 0; % Verschiebung in Z

                % Plot Pfeil in 3D
                plot3(app.UIAxes, Arrow2XRotated(2:8), Arrow2YRotated(2:8), Arrow2ZRotated(2:8), 'LineWidth', 2, 'Color', 'r');

                % Beschriftung in 3D
                text(app.UIAxes, Arrow2XRotated(1), Arrow2YRotated(1), Arrow2ZRotated(1), ...
                    "F = " + "5" + " N", 'FontSize', 15, 'Color', 'r');

                % Achseneinstellungen
                grid(app.UIAxes, 'on');
                axis(app.UIAxes, 'equal');
                view(app.UIAxes, 3); % Setzt 3D-Ansicht
            end

        end

        % Value changed function: ForceButton
        function ForceButtonPressed(app, event)
            app.BeamButton.Value = false;
            app.looseButton.Value = false;
            app.fixedButton.Value = false;
        end

        % Value changed function: BeamButton
        function BeamButtonPressed(app, event)
            app.ForceButton.Value = false;
            app.looseButton.Value = false;
            app.fixedButton.Value = false;
        end

        % Value changed function: looseButton
        function looseButtonPressed(app, event)
            app.BeamButton.Value = false;
            app.ForceButton.Value = false;
            app.fixedButton.Value = false;
        end

        % Callback function: not associated with a component
        function fixedButtonPressed(app, event)
            app.BeamButton.Value = false;
            app.looseButton.Value = false;
            app.ForceButton.Value = false;
        end

        % Callback function
        function TextAreaValueChanged(app, event)
            app.TextArea.Value = cellstr(num2str(app.PointsMatrix));
        end

        % Callback function
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: fixedButton
        function fixedButtonPRressed(app, event)
            app.BeamButton.Value = false;
            app.looseButton.Value = false;
            app.ForceButton.Value = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DEingabePanel = uipanel(app.GridLayout);
            app.DEingabePanel.Title = '3D Eingabe';
            app.GridLayout2 = uigridlayout(app.DEingabePanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Balken')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.PunktdefinierenPanel = uipanel(app.GridLayout3);
            app.PunktdefinierenPanel.Title = 'Punkt definieren';
            app.GridLayout4 = uigridlayout(app.PunktdefinierenPanel);
            app.Panel_2 = uipanel(app.GridLayout4);
            app.GridLayout5 = uigridlayout(app.Panel_2);
            app.XAxesEditFieldLabel = uilabel(app.GridLayout5);
            app.XAxesEditFieldLabel.Text = 'X Axes';
            app.XAxesEditField = uieditfield(app.GridLayout5, 'numeric');
            app.YAxesEditFieldLabel = uilabel(app.GridLayout5);
            app.YAxesEditFieldLabel.Text = 'Y Axes';
            app.YAxesEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ZAxesEditFieldLabel = uilabel(app.GridLayout5);
            app.ZAxesEditFieldLabel.Text = 'Z Axes';
            app.ZAxesEditField = uieditfield(app.GridLayout5, 'numeric');
            app.AddButton = uibutton(app.GridLayout4, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddPointButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.PunktauswhlenPanel = uipanel(app.GridLayout3);
            app.PunktauswhlenPanel.Title = 'Punkt auswählen';
            app.GridLayout6 = uigridlayout(app.PunktauswhlenPanel);
            app.ListBox = uilistbox(app.GridLayout6);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.ElementwhlenPanel = uipanel(app.GridLayout3);
            app.ElementwhlenPanel.Title = 'Element wählen';
            app.GridLayout7 = uigridlayout(app.ElementwhlenPanel);
            app.BeamButton = uibutton(app.GridLayout7, 'state');
            app.BeamButton.ValueChangedFcn = createCallbackFcn(app, @BeamButtonPressed, true);
            app.BeamButton.Text = 'Beam';
            app.looseButton = uibutton(app.GridLayout7, 'state');
            app.looseButton.ValueChangedFcn = createCallbackFcn(app, @looseButtonPressed, true);
            app.looseButton.Icon = fullfile(pathToMLAPP, 'Icons', 'loose_bearing.png');
            app.looseButton.Text = '';
            app.ForceButton = uibutton(app.GridLayout7, 'state');
            app.ForceButton.ValueChangedFcn = createCallbackFcn(app, @ForceButtonPressed, true);
            app.ForceButton.Icon = fullfile(pathToMLAPP, 'Icons', 'force.png');
            app.ForceButton.Text = '';
            app.fixedButton = uibutton(app.GridLayout7, 'state');
            app.fixedButton.ValueChangedFcn = createCallbackFcn(app, @fixedButtonPRressed, true);
            app.fixedButton.Icon = fullfile(pathToMLAPP, 'Icons', 'fixed_bearing.png');
            app.fixedButton.Text = '';
            app.WinkeldefinierenPanel = uipanel(app.GridLayout3);
            app.WinkeldefinierenPanel.Title = 'Winkel definieren';
            app.GridLayout8 = uigridlayout(app.WinkeldefinierenPanel);
            app.AddButton_2 = uibutton(app.GridLayout8, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddItemButtonPushed, true);
            app.AddButton_2.Text = 'Add';
            app.Panel_3 = uipanel(app.GridLayout8);
            app.GridLayout9 = uigridlayout(app.Panel_3);
            app.ZAxesEditField_2Label = uilabel(app.GridLayout9);
            app.ZAxesEditField_2Label.Text = 'α (Z Axes)';
            app.ZAxesEditField_2 = uieditfield(app.GridLayout9, 'numeric');
            app.YAxesEditField_2Label = uilabel(app.GridLayout9);
            app.YAxesEditField_2Label.Text = 'β (Y Axes)';
            app.YAxesEditField_2 = uieditfield(app.GridLayout9, 'numeric');
            app.Label = uilabel(app.GridLayout9);
            app.Label.Text = 'γ (X Axes)';
            app.XAxesEditField_2 = uieditfield(app.GridLayout9, 'numeric');
            app.Panel_4 = uipanel(app.GridLayout3);
            app.GridLayout10 = uigridlayout(app.Panel_4);
            app.EditFieldLabel = uilabel(app.GridLayout10);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.GridLayout10, 'numeric');
            app.EditField2Label = uilabel(app.GridLayout10);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.GridLayout10, 'numeric');
            app.EditField3Label = uilabel(app.GridLayout10);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.GridLayout10, 'numeric');
            app.Panel_5 = uipanel(app.GridLayout2);
            app.Panel_5.Title = 'Panel';
            app.GridLayout11 = uigridlayout(app.Panel_5);
            app.Label_2 = uilabel(app.GridLayout11);
            app.Label_2.Text = '';
            app.TextArea = uitextarea(app.GridLayout11);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Eingabe3D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Disp3d)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end