classdef InitialSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        Panel                      matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        TabGroup                   matlab.ui.container.TabGroup
        WelcomeTab                 matlab.ui.container.Tab
        GridLayout4                matlab.ui.container.GridLayout
        Panel_17                   matlab.ui.container.Panel
        GridLayout8                matlab.ui.container.GridLayout
        CalculationofinternalforcesandreactionsinsideofbarsLabel  matlab.ui.control.Label
        Panel_15                   matlab.ui.container.Panel
        GridLayout7_4              matlab.ui.container.GridLayout
        Panel_16                   matlab.ui.container.Panel
        Image_4                    matlab.ui.control.Image
        WelcometoCiFRiBLabel_9     matlab.ui.control.Label
        Panel_4                    matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        NextButton                 matlab.ui.control.Button
        GettingStartedTab          matlab.ui.container.Tab
        GridLayout4_5              matlab.ui.container.GridLayout
        Panel_18                   matlab.ui.container.Panel
        GridLayout9                matlab.ui.container.GridLayout
        HTML                       matlab.ui.control.HTML
        Panel_11                   matlab.ui.container.Panel
        GridLayout7_2              matlab.ui.container.GridLayout
        Panel_12                   matlab.ui.container.Panel
        Image_2                    matlab.ui.control.Image
        WelcometoCiFRiBLabel_7     matlab.ui.control.Label
        Panel_6                    matlab.ui.container.Panel
        GridLayout3_3              matlab.ui.container.GridLayout
        BackButton_3               matlab.ui.control.Button
        NextButton_3               matlab.ui.control.Button
        StartTab                   matlab.ui.container.Tab
        GridLayout4_6              matlab.ui.container.GridLayout
        Panel_9                    matlab.ui.container.Panel
        GridLayout7                matlab.ui.container.GridLayout
        Panel_10                   matlab.ui.container.Panel
        Image                      matlab.ui.control.Image
        WelcometoCiFRiBLabel_6     matlab.ui.control.Label
        Panel_8                    matlab.ui.container.Panel
        GridLayout5_2              matlab.ui.container.GridLayout
        CheckBox_InitialSetupHide  matlab.ui.control.CheckBox
        DropDown_Theme             matlab.ui.control.DropDown
        ThemeDropDownLabel         matlab.ui.control.Label
        DropDown_Language          matlab.ui.control.DropDown
        LanguageLabel              matlab.ui.control.Label
        Panel_7                    matlab.ui.container.Panel
        GridLayout3_4              matlab.ui.container.GridLayout
        BackButton_4               matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            try
                global sessionId deviceId programVersion
                sendAnalytics(sessionId, deviceId, programVersion,'initial_setup');
            catch
            end
            app.UIFigure.WindowStyle = "alwaysontop";
            app.UIFigure.WindowStyle = "modal";
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.GettingStartedTab;
        end

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.WelcomeTab;
        end

        % Button pushed function: BackButton_4
        function BackButton_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.GettingStartedTab;
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.StartTab;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.CheckBox_InitialSetupHide.Value
                setpref('cifrib', 'hideInitialSetup', true);
            elseif ~app.CheckBox_InitialSetupHide.Value
                setpref('cifrib', 'hideInitialSetup', false);
            end
            %set variable for language
            switch app.DropDown_Language.Value
                case 'English'
                    setpref('cifrib','language','english');
                case 'Deutsch'
                    setpref('cifrib','language','german');
            end
            applyLanguage(app.MainApp)

            % set variable for theme
            switch app.DropDown_Theme.Value
                case 'Light'
                    setpref('cifrib','theme','light');
                case 'Dark'
                    setpref('cifrib','theme','dark');
            end
            %applyTheme(app.MainApp)
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.GridLayout4 = uigridlayout(app.WelcomeTab);
            app.Panel_4 = uipanel(app.GridLayout4);
            app.GridLayout3 = uigridlayout(app.Panel_4);
            app.NextButton = uibutton(app.GridLayout3, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Panel_15 = uipanel(app.GridLayout4);
            app.GridLayout7_4 = uigridlayout(app.Panel_15);
            app.WelcometoCiFRiBLabel_9 = uilabel(app.GridLayout7_4);
            app.WelcometoCiFRiBLabel_9.Text = 'Welcome to CiFRiB';
            app.Image_4 = uiimage(app.GridLayout7_4);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'icons', 'logo_black_background_quadratic.png');
            app.Panel_16 = uipanel(app.GridLayout7_4);
            app.Panel_17 = uipanel(app.GridLayout4);
            app.GridLayout8 = uigridlayout(app.Panel_17);
            app.CalculationofinternalforcesandreactionsinsideofbarsLabel = uilabel(app.GridLayout8);
            app.CalculationofinternalforcesandreactionsinsideofbarsLabel.Text = '    Calculation of internal forces and reactions inside of bars';
            app.GettingStartedTab = uitab(app.TabGroup);
            app.GettingStartedTab.Title = 'Getting Started';
            app.GridLayout4_5 = uigridlayout(app.GettingStartedTab);
            app.Panel_6 = uipanel(app.GridLayout4_5);
            app.GridLayout3_3 = uigridlayout(app.Panel_6);
            app.NextButton_3 = uibutton(app.GridLayout3_3, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next';
            app.BackButton_3 = uibutton(app.GridLayout3_3, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = 'Back';
            app.Panel_11 = uipanel(app.GridLayout4_5);
            app.GridLayout7_2 = uigridlayout(app.Panel_11);
            app.WelcometoCiFRiBLabel_7 = uilabel(app.GridLayout7_2);
            app.WelcometoCiFRiBLabel_7.Text = 'Welcome to CiFRiB';
            app.Image_2 = uiimage(app.GridLayout7_2);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'icons', 'logo_black_background_quadratic.png');
            app.Panel_12 = uipanel(app.GridLayout7_2);
            app.Panel_18 = uipanel(app.GridLayout4_5);
            app.GridLayout9 = uigridlayout(app.Panel_18);
            app.HTML = uihtml(app.GridLayout9);
            app.HTML.HTMLSource = 'guide_en.html';
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.GridLayout4_6 = uigridlayout(app.StartTab);
            app.Panel_7 = uipanel(app.GridLayout4_6);
            app.GridLayout3_4 = uigridlayout(app.Panel_7);
            app.StartButton = uibutton(app.GridLayout3_4, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.BackButton_4 = uibutton(app.GridLayout3_4, 'push');
            app.BackButton_4.ButtonPushedFcn = createCallbackFcn(app, @BackButton_4Pushed, true);
            app.BackButton_4.Text = 'Back';
            app.Panel_8 = uipanel(app.GridLayout4_6);
            app.GridLayout5_2 = uigridlayout(app.Panel_8);
            app.LanguageLabel = uilabel(app.GridLayout5_2);
            app.LanguageLabel.Text = 'Language';
            app.DropDown_Language = uidropdown(app.GridLayout5_2);
            app.DropDown_Language.Items = {'English', 'Deutsch'};
            app.DropDown_Language.Value = 'English';
            app.ThemeDropDownLabel = uilabel(app.GridLayout5_2);
            app.ThemeDropDownLabel.Enable = 'off';
            app.ThemeDropDownLabel.Text = 'Theme';
            app.DropDown_Theme = uidropdown(app.GridLayout5_2);
            app.DropDown_Theme.Items = {'Light', 'Dark'};
            app.DropDown_Theme.Enable = 'off';
            app.DropDown_Theme.Tooltip = {'Coming soon!'};
            app.DropDown_Theme.Value = 'Light';
            app.CheckBox_InitialSetupHide = uicheckbox(app.GridLayout5_2);
            app.CheckBox_InitialSetupHide.Text = 'Dont show this setup at startup';
            app.CheckBox_InitialSetupHide.Value = true;
            app.Panel_9 = uipanel(app.GridLayout4_6);
            app.GridLayout7 = uigridlayout(app.Panel_9);
            app.WelcometoCiFRiBLabel_6 = uilabel(app.GridLayout7);
            app.WelcometoCiFRiBLabel_6.Text = 'Welcome to CiFRiB';
            app.Image = uiimage(app.GridLayout7);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'logo_black_background_quadratic.png');
            app.Panel_10 = uipanel(app.GridLayout7);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InitialSetup(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end