classdef addForceDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        addnewforcePanel         matlab.ui.container.Panel
        valueEditFieldLabel      matlab.ui.control.Label
        valueEF                  matlab.ui.control.NumericEditField
        NLabel                   matlab.ui.control.Label
        mLabel                   matlab.ui.control.Label
        positionxEditFieldLabel  matlab.ui.control.Label
        positionEF               matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        angleEditFieldLabel      matlab.ui.control.Label
        angleEF                  matlab.ui.control.NumericEditField
        okButton                 matlab.ui.control.Button
        cancelButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainGUI
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.MainGUI = mainApp;
        end

        % Button pushed function: okButton
        function okButtonButtonPushed(app, event)
            
            value = app.valueEF.Value;
            position = app.positionEF.Value;
            angle = app.angleEF.Value;
              
            % checking for valid user inputs
            if value<=0
                uialert(app.UIFigure,"Please enter a valid value (greater than zero)","Error");

            elseif  position<0
                uialert(app.UIFigure,"Please enter a valid position (greater than zero)","Error");
                
            else
                app.MainGUI.addForce(value, position, angle);
                delete(app);
            end 
        end

        % Button pushed function: cancelButton
        function cancelButtonButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.addnewforcePanel = uipanel(app.UIFigure);
            app.addnewforcePanel.Title = 'add new force ';
            app.valueEditFieldLabel = uilabel(app.addnewforcePanel);
            app.valueEditFieldLabel.Text = 'value';
            app.valueEF = uieditfield(app.addnewforcePanel, 'numeric');
            app.NLabel = uilabel(app.addnewforcePanel);
            app.NLabel.Text = 'N';
            app.mLabel = uilabel(app.addnewforcePanel);
            app.mLabel.Text = 'm';
            app.positionxEditFieldLabel = uilabel(app.addnewforcePanel);
            app.positionxEditFieldLabel.Text = 'position (x)';
            app.positionEF = uieditfield(app.addnewforcePanel, 'numeric');
            app.Label = uilabel(app.addnewforcePanel);
            app.Label.Text = '°';
            app.angleEditFieldLabel = uilabel(app.addnewforcePanel);
            app.angleEditFieldLabel.Text = 'angle';
            app.angleEF = uieditfield(app.addnewforcePanel, 'numeric');
            app.okButton = uibutton(app.UIFigure, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonButtonPushed, true);
            app.okButton.Text = 'OK';
            app.cancelButton = uibutton(app.UIFigure, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonButtonPushed, true);
            app.cancelButton.Text = 'cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = addForceDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end