classdef addLineLoadDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        addnewlineloadPanel          matlab.ui.container.Panel
        mLabel                       matlab.ui.control.Label
        startxLabel                  matlab.ui.control.Label
        startEF                      matlab.ui.control.NumericEditField
        mLabel_2                     matlab.ui.control.Label
        endxLabel                    matlab.ui.control.Label
        endEF                        matlab.ui.control.NumericEditField
        enterfullequationinxNmLabel  matlab.ui.control.Label
        lineLoadEF                   matlab.ui.control.EditField
        helpButton                   matlab.ui.control.Button
        okButton                     matlab.ui.control.Button
        cancelButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainGUI 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.MainGUI = mainApp;
        end

        % Button pushed function: okButton
        function okButtonPushed(app, event)
            
            equation = app.lineLoadEF.Value;
            startPoint = app.startEF.Value;
            endPoint = app.endEF.Value;
                      
            % checking for valid user inputs
            if strcmp(equation,"")
                uialert(app.UIFigure,"Please enter an equation","Error");

            elseif startPoint<0
                uialert(app.UIFigure,"Please enter a valid start point (greater than zero)","Error");

            elseif endPoint<0
                uialert(app.UIFigure,"Please enter a valid end point (greater than zero)","Error");
            
            elseif endPoint<=startPoint
                uialert(app.UIFigure,"Please make sure that the end point is greater than the start point","Error");
            
            else
                app.MainGUI.addLineLoad(app.lineLoadEF.Value, app.startEF.Value, app.endEF.Value);
                delete(app);
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: helpButton
        function helpButtonPushed(app, event)
            helpLineLoadDialog();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.addnewlineloadPanel = uipanel(app.UIFigure);
            app.addnewlineloadPanel.Title = 'add new line load';
            app.mLabel = uilabel(app.addnewlineloadPanel);
            app.mLabel.Text = 'm';
            app.startxLabel = uilabel(app.addnewlineloadPanel);
            app.startxLabel.Text = 'start (x)';
            app.startEF = uieditfield(app.addnewlineloadPanel, 'numeric');
            app.mLabel_2 = uilabel(app.addnewlineloadPanel);
            app.mLabel_2.Text = 'm';
            app.endxLabel = uilabel(app.addnewlineloadPanel);
            app.endxLabel.Text = 'end (x)';
            app.endEF = uieditfield(app.addnewlineloadPanel, 'numeric');
            app.enterfullequationinxNmLabel = uilabel(app.addnewlineloadPanel);
            app.enterfullequationinxNmLabel.Text = 'enter full equation in x [N/m]:';
            app.lineLoadEF = uieditfield(app.addnewlineloadPanel, 'text');
            app.helpButton = uibutton(app.addnewlineloadPanel, 'push');
            app.helpButton.ButtonPushedFcn = createCallbackFcn(app, @helpButtonPushed, true);
            app.helpButton.Text = '?';
            app.okButton = uibutton(app.UIFigure, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Text = 'OK';
            app.cancelButton = uibutton(app.UIFigure, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Text = 'cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = addLineLoadDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end